classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        PlotOptionsMenu               matlab.ui.container.Menu
        EnablehighqualitymodelsMenu   matlab.ui.container.Menu
        EnablecoordinateframeMenu     matlab.ui.container.Menu
        EnableghostmodelsMenu         matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        WindowMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DoFSpinner                    matlab.ui.control.Spinner
        DoFSpinnerLabel               matlab.ui.control.Label
        EditButton                    matlab.ui.control.StateButton
        EndEffectorDropDown           matlab.ui.control.DropDown
        EndEffectorDropDownLabel      matlab.ui.control.Label
        RobotModelDropDown            matlab.ui.control.DropDown
        RobotModelDropDownLabel       matlab.ui.control.Label
        SimulationPanel               matlab.ui.container.Panel
        StepsEditField                matlab.ui.control.NumericEditField
        StepsEditFieldLabel           matlab.ui.control.Label
        FinalsEditField               matlab.ui.control.NumericEditField
        FinalsEditFieldLabel          matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        SimModeSwitch                 matlab.ui.control.Switch
        SimModeSwitchLabel            matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        LeftTabGroup                  matlab.ui.container.TabGroup
        KinematicsTab                 matlab.ui.container.Tab
        jLabel                        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        q_7ButtonGroup                matlab.ui.container.ButtonGroup
        q_7Label                      matlab.ui.control.Label
        Button2_7                     matlab.ui.control.RadioButton
        Button_7                      matlab.ui.control.RadioButton
        q_6ButtonGroup                matlab.ui.container.ButtonGroup
        q_6Label                      matlab.ui.control.Label
        Button2_6                     matlab.ui.control.RadioButton
        Button_6                      matlab.ui.control.RadioButton
        q_5ButtonGroup                matlab.ui.container.ButtonGroup
        q_5Label                      matlab.ui.control.Label
        Button2_5                     matlab.ui.control.RadioButton
        Button_5                      matlab.ui.control.RadioButton
        q_4ButtonGroup                matlab.ui.container.ButtonGroup
        q_4Label                      matlab.ui.control.Label
        Button2_4                     matlab.ui.control.RadioButton
        Button_4                      matlab.ui.control.RadioButton
        q_3ButtonGroup                matlab.ui.container.ButtonGroup
        q_3Label                      matlab.ui.control.Label
        Button2_3                     matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        q_2ButtonGroup                matlab.ui.container.ButtonGroup
        q_2Label                      matlab.ui.control.Label
        Button2_2                     matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        q_1ButtonGroup                matlab.ui.container.ButtonGroup
        q_1Label                      matlab.ui.control.Label
        Button2                       matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        q_jPanel                      matlab.ui.container.Panel
        q_jRPLabel                    matlab.ui.control.Label
        mmdegmmdegLabel               matlab.ui.control.Label
        DHTable                       matlab.ui.control.Table
        DHTableLabel                  matlab.ui.control.Label
        LimitsTab                     matlab.ui.container.Tab
        JointAccMagdegsLabel          matlab.ui.control.Label
        JointAccMagTable              matlab.ui.control.Table
        JointVelMagdegsLabel          matlab.ui.control.Label
        JointVelMagTable              matlab.ui.control.Table
        JointPosMindegLabel           matlab.ui.control.Label
        JointPosMinTable              matlab.ui.control.Table
        JointPosMaxdegLabel           matlab.ui.control.Label
        JointPosMaxTable              matlab.ui.control.Table
        DynamicsTab                   matlab.ui.container.Tab
        InertialParametersPanel       matlab.ui.container.Panel
        CenterofMassTable_2           matlab.ui.control.Table
        UniqueElementsVectorofInertiaTensorsmLabel  matlab.ui.control.Label
        CenterofMassmLabel            matlab.ui.control.Label
        CenterofMassTable             matlab.ui.control.Table
        MasskgLabel                   matlab.ui.control.Label
        MassTable                     matlab.ui.control.Table
        FrictionCheckBox              matlab.ui.control.CheckBox
        SpringCheckBox                matlab.ui.control.CheckBox
        AdditionalmodelsLabel         matlab.ui.control.Label
        ControlTab                    matlab.ui.container.Tab
        ControlTypeDropDown           matlab.ui.control.DropDown
        ControlTypeDropDownLabel      matlab.ui.control.Label
        CycleEditField                matlab.ui.control.NumericEditField
        CycleEditFieldLabel           matlab.ui.control.Label
        secLabel_2                    matlab.ui.control.Label
        CompGravityCheckBox           matlab.ui.control.CheckBox
        CompFrictionCheckBox          matlab.ui.control.CheckBox
        CompSpringCheckBox            matlab.ui.control.CheckBox
        GainTable                     matlab.ui.control.Table
        ControlGainsLabel             matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        ModelPanel                    matlab.ui.container.Panel
        DmodelsSwitch                 matlab.ui.control.Switch
        DModelLabel                   matlab.ui.control.Label
        HighqualitymodelsSwitch       matlab.ui.control.Switch
        HighqualitymodelsSwitchLabel  matlab.ui.control.Label
        SlowerLabel                   matlab.ui.control.Label
        TimePanel                     matlab.ui.container.Panel
        q_actq1q2q3q4q5q6q7degLabel   matlab.ui.control.Label
        x1RealtimeLabel               matlab.ui.control.Label
        secLabel                      matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TrajectoryPanel               matlab.ui.container.Panel
        ButtonGroup_8                 matlab.ui.container.ButtonGroup
        TaskButton                    matlab.ui.control.RadioButton
        JointButton                   matlab.ui.control.RadioButton
        VelEditField                  matlab.ui.control.NumericEditField
        VelEditFieldLabel             matlab.ui.control.Label
        AccEditField                  matlab.ui.control.NumericEditField
        AccEditFieldLabel             matlab.ui.control.Label
        InterpolationDropDown         matlab.ui.control.DropDown
        InterpolationLabel            matlab.ui.control.Label
        AccPrcLabel                   matlab.ui.control.Label
        VelPrcLabel                   matlab.ui.control.Label
        LimitsLabel                   matlab.ui.control.Label
        RightTabGroup                 matlab.ui.container.TabGroup
        InitialJointTab               matlab.ui.container.Tab
        GripperInitialSpinner         matlab.ui.control.Spinner
        Axis7InitialSpinner           matlab.ui.control.Spinner
        Axis6InitialSpinner           matlab.ui.control.Spinner
        Axis5InitialSpinner           matlab.ui.control.Spinner
        Axis4InitialSpinner           matlab.ui.control.Spinner
        Axis3InitialSpinner           matlab.ui.control.Spinner
        Axis2InitialSpinner           matlab.ui.control.Spinner
        Axis1InitialSpinner           matlab.ui.control.Spinner
        GripperInitialSlider          matlab.ui.control.Slider
        GripperInitialLabel           matlab.ui.control.Label
        Axis7InitialSlider            matlab.ui.control.Slider
        Axis7InitialLabel             matlab.ui.control.Label
        Axis6InitialSlider            matlab.ui.control.Slider
        Axis6InitialLabel             matlab.ui.control.Label
        Axis5InitialSlider            matlab.ui.control.Slider
        Axis5InitialLabel             matlab.ui.control.Label
        Axis4InitialSlider            matlab.ui.control.Slider
        Axis4InitialLabel             matlab.ui.control.Label
        Axis3InitialSlider            matlab.ui.control.Slider
        Axis3InitialLabel             matlab.ui.control.Label
        Axis2InitialSlider            matlab.ui.control.Slider
        Axis2InitialLabel             matlab.ui.control.Label
        Axis1InitialSlider            matlab.ui.control.Slider
        Axis1InitialLabel             matlab.ui.control.Label
        FinalJointTab                 matlab.ui.container.Tab
        GripperFinalSlider            matlab.ui.control.Slider
        GripperFinalLabel             matlab.ui.control.Label
        Axis7FinalSlider              matlab.ui.control.Slider
        Axis7FinalLabel               matlab.ui.control.Label
        Axis6FinalSlider              matlab.ui.control.Slider
        Axis6FinalLabel               matlab.ui.control.Label
        Axis5FinalSlider              matlab.ui.control.Slider
        Axis5FinalLabel               matlab.ui.control.Label
        Axis4FinalSlider              matlab.ui.control.Slider
        Axis4FinalLabel               matlab.ui.control.Label
        Axis3FinalSlider              matlab.ui.control.Slider
        Axis3FinalLabel               matlab.ui.control.Label
        Axis2FinalSlider              matlab.ui.control.Slider
        Axis2FinalLabel               matlab.ui.control.Label
        Axis1FinalSlider              matlab.ui.control.Slider
        Axis1FinalLabel               matlab.ui.control.Label
        GripperFinalSpinner           matlab.ui.control.Spinner
        Axis7FinalSpinner             matlab.ui.control.Spinner
        Axis6FinalSpinner             matlab.ui.control.Spinner
        Axis5FinalSpinner             matlab.ui.control.Spinner
        Axis4FinalSpinner             matlab.ui.control.Spinner
        Axis3FinalSpinner             matlab.ui.control.Spinner
        Axis2FinalSpinner             matlab.ui.control.Spinner
        Axis1FinalSpinner             matlab.ui.control.Spinner
        InitialTaskTab                matlab.ui.container.Tab
        GripperTaskInitialSlider      matlab.ui.control.Slider
        GripperTaskInitialLabel       matlab.ui.control.Label
        Axis7InitialSlider_2          matlab.ui.control.Slider
        Axis7InitialLabel_2           matlab.ui.control.Label
        PsiInitialSlider              matlab.ui.control.Slider
        PsiInitialLabel               matlab.ui.control.Label
        ThetaInitialSlider            matlab.ui.control.Slider
        ThetaInitialLabel             matlab.ui.control.Label
        PhiInitialSlider              matlab.ui.control.Slider
        PhiInitialLabel               matlab.ui.control.Label
        ZAxisInitialSlider            matlab.ui.control.Slider
        ZAxisInitialLabel             matlab.ui.control.Label
        YAxisInitialSlider            matlab.ui.control.Slider
        YAxisInitialLabel             matlab.ui.control.Label
        XAxisInitialSlider            matlab.ui.control.Slider
        XAxisInitialLabel             matlab.ui.control.Label
        GripperTaskInitialSpinner     matlab.ui.control.Spinner
        Axis7InitialSpinner_2         matlab.ui.control.Spinner
        PsiInitialSpinner             matlab.ui.control.Spinner
        ThetaInitialSpinner           matlab.ui.control.Spinner
        PhiInitialSpinner             matlab.ui.control.Spinner
        ZAxisInitialSpinner           matlab.ui.control.Spinner
        YAxisInitialSpinner           matlab.ui.control.Spinner
        XAxisInitialSpinner           matlab.ui.control.Spinner
        FinalTaskTab                  matlab.ui.container.Tab
        GripperTaskFinalSlider        matlab.ui.control.Slider
        GripperTaskFinalLabel         matlab.ui.control.Label
        Axis7FinalSlider_2            matlab.ui.control.Slider
        Axis7FinalLabel_2             matlab.ui.control.Label
        PsiFinalSlider                matlab.ui.control.Slider
        PsiFinalLabel                 matlab.ui.control.Label
        ThetaFinalSlider              matlab.ui.control.Slider
        ThetaFinalLabel               matlab.ui.control.Label
        PhiFinalSlider                matlab.ui.control.Slider
        PhiFinalLabel                 matlab.ui.control.Label
        ZAxisFinalSlider              matlab.ui.control.Slider
        ZAxisFinalLabel               matlab.ui.control.Label
        YAxisFinalSlider              matlab.ui.control.Slider
        YAxisFinalLabel               matlab.ui.control.Label
        XAxisFinalSlider              matlab.ui.control.Slider
        XAxisFinalLabel               matlab.ui.control.Label
        GripperTaskFinalSpinner       matlab.ui.control.Spinner
        Axis7FinalSpinner_2           matlab.ui.control.Spinner
        PsiFinalSpinner               matlab.ui.control.Spinner
        ThetaFinalSpinner             matlab.ui.control.Spinner
        PhiFinalSpinner               matlab.ui.control.Spinner
        ZAxisFinalSpinner             matlab.ui.control.Spinner
        YAxisFinalSpinner             matlab.ui.control.Spinner
        XAxisFinalSpinner             matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        
        prcnt = [0.1,0.1];
        trj_profile_text = 'Quintic';

        tini = 0; % Start time

    end
    
    properties (Access = public)
        
        robot_name = [
        "Franka Emika Robot"
        "Universal Robots UR3"
        "Unitree Z1"
        "Stäubli RX160"
        "Stäubli RX160L"
        "Custom Robot"];

        ms;
        Pobj_f;
        Pobj_d;
        Pobj_r;

        high_quality;
        coord_frame_on;
        ghost_on = 1;
        line_on = 0;

        running_flag = 0;

        trj_profile = 3;

        robot_model = 1;
        ee_att = 1;

        ctr;
        kin;
        dyn;

        ini;
        fin;
        des;
        act;
        fbk;
        
        TI_0 = eye(4);

        qEIni;
        qEDes;

        tstp = 0.001; % Time step
        tfin = 0; % Final simulation time

        tfin_trj = 0; % Final trajectory time
        time_const = 0.1;
        
        exceeding = 0;
        grip = 0;

        sim_mode;

        DH_custom;
        n_custom;
        m_j_custom;
        rj_jcj_custom;
        Ij_cj_custom;
        j_type_custom;

        task_mode = 0;
        eulerSet = 1;
        
    end
    
    methods (Access = public)
        
        function updateRobotModel(app)

            if app.robot_model ~= 0
                app.RobotModelDropDown.Value = char(app.robot_name(app.robot_model));
                if strcmpi(app.DmodelsSwitch.Value,'On') == 1
                    app.line_on = 0;
                else
                    app.line_on = 1;
                end
                app.EditButton.Enable = "off";
                app.DoFSpinner.Enable = "off";
                app.DmodelsSwitch.Visible = "on";
                app.DModelLabel.Visible = "on";
                app.EndEffectorDropDown.Visible = "on";
                app.EndEffectorDropDownLabel.Visible = "on";
            
                app.kin = kinematicParameters(app.robot_model,app.ee_att);

                app.dyn = stdInertialParameters(app.robot_model);

                app.q_1ButtonGroup.Enable = "off";
                app.q_2ButtonGroup.Enable = "off";
                app.q_3ButtonGroup.Enable = "off";
                app.q_4ButtonGroup.Enable = "off";
                app.q_5ButtonGroup.Enable = "off";
                app.q_6ButtonGroup.Enable = "off";
                app.q_7ButtonGroup.Enable = "off";
            else
                app.line_on = 1;
                app.RobotModelDropDown.Value = char(app.robot_name(size(app.robot_name,1)));
                app.EditButton.Enable = "on";
                if app.EditButton.Value == true
                    app.DoFSpinner.Enable = "on";
                else
                    app.DoFSpinner.Enable = "off";
                end
                app.DmodelsSwitch.Visible = "off";
                app.DModelLabel.Visible = "off";
                app.EndEffectorDropDown.Visible = "off";
                app.EndEffectorDropDownLabel.Visible = "off";

                app.kin.n = app.n_custom;
                app.kin.a_j = app.DH_custom(1:app.kin.n+1,1);
                app.kin.alpha_j = app.DH_custom(1:app.kin.n+1,2);
                app.kin.d_j = app.DH_custom(1:app.kin.n+1,3);
                app.kin.theta_O_j = app.DH_custom(1:app.kin.n+1,4);

                app.kin.j_type = app.j_type_custom(1:app.kin.n,1);

                % joint revolute limits (rad): (q1..q6)
                qR_min = [-pi   -pi   -pi   -pi   -pi   -pi   -pi]'; % [rad]
                qR_max = [ pi    pi    pi    pi    pi    pi    pi]'; % [rad]
                % joint velocity limits (rad/s): (q1..q6)
                dqR_max = [pi pi pi pi pi pi pi]';
                dqR_min = -dqR_max;
                % joint acceleration limits (rad/s^2): (q1..q6)
                ddqR_max = [20 20 20 20 20 20 20]';
                ddqR_min = -ddqR_max; 

                % joint prismatic limits (mm): (q1..q6)
                qP_min = [-0.1   -0.1   -0.1   -0.1   -0.1   -0.1   -0.1]'; % [rad]
                qP_max = [ 0.1    0.1    0.1    0.1    0.1    0.1    0.1]'; % [rad]
                % joint velocity limits (m/s): (q1..q6)
                dqP_max = [1 1 1 1 1 1 1]';
                dqP_min = -dqP_max;
                % joint acceleration limits (m/s^2): (q1..q6)
                ddqP_max = [1 1 1 1 1 1 1]';
                ddqP_min = -ddqP_max; 

                app.kin.q_posLim = zeros(app.kin.n,2);
                app.kin.q_velLim = zeros(app.kin.n,2);
                app.kin.q_accLim = zeros(app.kin.n,2);

                for j = 1:app.kin.n
                    if app.kin.j_type(j,1) == 1
                        app.kin.q_posLim(j,:) = [qR_min(j,1),qR_max(j,1)];
                        app.kin.q_velLim(j,:) = [dqR_min(j,1),dqR_max(j,1)];
                        app.kin.q_accLim(j,:) = [ddqR_min(j,1),ddqR_max(j,1)];
                    else
                        app.kin.q_posLim(j,:) = [qP_min(j,1),qP_max(j,1)];
                        app.kin.q_velLim(j,:) = [dqP_min(j,1),dqP_max(j,1)];
                        app.kin.q_accLim(j,:) = [ddqP_min(j,1),ddqP_max(j,1)];
                    end
                end

                app.dyn.m_j = app.m_j_custom;
                app.dyn.rj_jcj = app.rj_jcj_custom;
                app.dyn.Ij_cj = app.Ij_cj_custom;
            
                app.kin.zj_j = [0;0;1];
            end

            app.kin.ri_j = zeros(3,1,app.kin.n+2);
            for j = 1:app.kin.n+1
                app.kin.ri_j(:,:,j) = getri_j_vec(app.kin.alpha_j(j),app.kin.a_j(j),app.kin.d_j(j));
            end

            GRAV = 9.81; % gravity constant
            app.kin.g0 = -[0;0;GRAV]; % gravitational acceleration vector

            app.dyn.friction_on = 1;
            if app.robot_model == 4 || app.robot_model == 5
                app.SpringCheckBox.Visible = "on";
                app.dyn.spring_on = 1;
            else
                app.SpringCheckBox.Visible = "off";
                app.dyn.spring_on = 0;
            end

            pMax = zeros(app.kin.n,1);
            pMin = zeros(app.kin.n,1);
            vMag = zeros(app.kin.n,1);
            aMag = zeros(app.kin.n,1);
            for j = 1:app.kin.n
                if app.kin.j_type(j,1) == 1
                    pMax(j,1) = round(rad2deg(app.kin.q_posLim(j,2)),2);
                    pMin(j,1) = round(rad2deg(app.kin.q_posLim(j,1)),2);
                    vMag(j,1) = round(rad2deg(app.kin.q_velLim(j,2)),2);
                    aMag(j,1) = round(rad2deg(app.kin.q_accLim(j,2)),2);
                else
                    pMax(j,1) = round(1e3*app.kin.q_posLim(j,2),2);
                    pMin(j,1) = round(1e3*app.kin.q_posLim(j,1),2);
                    vMag(j,1) = round(1e3*app.kin.q_velLim(j,2),2);
                    aMag(j,1) = round(1e3*app.kin.q_accLim(j,2),2);
                end
            end

            app.kin.r_posLim = [-pi pi
                                -pi pi
                                -pi pi];
            app.kin.r_velLim = [-pi/4 pi/4];
            app.kin.r_accLim = [-pi/8 pi/8];

            app.DoFSpinner.Value = app.kin.n;
            if app.kin.n == 7
                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3)))); ...
                             num2str(app.kin.a_j(4)*1e3) num2str(rad2deg(app.kin.alpha_j(4))) num2str(app.kin.d_j(4)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(4)))); ...
                             num2str(app.kin.a_j(5)*1e3) num2str(rad2deg(app.kin.alpha_j(5))) num2str(app.kin.d_j(5)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(5)))); ...
                             num2str(app.kin.a_j(6)*1e3) num2str(rad2deg(app.kin.alpha_j(6))) num2str(app.kin.d_j(6)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(6)))); ...
                             num2str(app.kin.a_j(7)*1e3) num2str(rad2deg(app.kin.alpha_j(7))) num2str(app.kin.d_j(7)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(7)))); ...
                             num2str(app.kin.a_j(8)*1e3) num2str(rad2deg(app.kin.alpha_j(8))) num2str(app.kin.d_j(8)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(8))))};

                LinkNames = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; 'E'};
                
                app.JointPosMaxTable.Data = {num2str(round(pMax(1,1),1)) num2str(round(pMax(2,1),1)) num2str(round(pMax(3,1),1)) num2str(round(pMax(4,1),1)) num2str(round(pMax(5,1),1)) num2str(round(pMax(6,1),1)) num2str(round(pMax(7,1),1))};
                app.JointPosMinTable.Data = {num2str(round(pMin(1,1),1)) num2str(round(pMin(2,1),1)) num2str(round(pMin(3,1),1)) num2str(round(pMin(4,1),1)) num2str(round(pMin(5,1),1)) num2str(round(pMin(6,1),1)) num2str(round(pMin(7,1),1))};
                app.JointVelMagTable.Data = {num2str(round(vMag(1,1),1)) num2str(round(vMag(2,1),1)) num2str(round(vMag(3,1),1)) num2str(round(vMag(4,1),1)) num2str(round(vMag(5,1),1)) num2str(round(vMag(6,1),1)) num2str(round(vMag(7,1),1))};
                app.JointAccMagTable.Data = {num2str(round(aMag(1,1),1)) num2str(round(aMag(2,1),1)) num2str(round(aMag(3,1),1)) num2str(round(aMag(4,1),1)) num2str(round(aMag(5,1),1)) num2str(round(aMag(6,1),1)) num2str(round(aMag(7,1),1))};

                app.JointPosMaxTable.ColumnWidth = {40,40,40,40,40,40,40};
                app.JointPosMinTable.ColumnWidth = {40,40,40,40,40,40,40};
                app.JointVelMagTable.ColumnWidth = {40,40,40,40,40,40,40};
                app.JointAccMagTable.ColumnWidth = {40,40,40,40,40,40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36,36,36,36,36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2)) num2str(round(app.dyn.m_j(3),2)) num2str(round(app.dyn.m_j(4),2)) ...
                                        num2str(round(app.dyn.m_j(5),2)) num2str(round(app.dyn.m_j(6),2)) num2str(round(app.dyn.m_j(7),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36,36,36,36,36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)) num2str(round(app.dyn.rj_jcj(1,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(1,1,4),2)) num2str(round(app.dyn.rj_jcj(1,1,5),2)) num2str(round(app.dyn.rj_jcj(1,1,6),2)) num2str(round(app.dyn.rj_jcj(1,1,7),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)) num2str(round(app.dyn.rj_jcj(2,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(2,1,4),2)) num2str(round(app.dyn.rj_jcj(2,1,5),2)) num2str(round(app.dyn.rj_jcj(2,1,6),2)) num2str(round(app.dyn.rj_jcj(2,1,7),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2)) num2str(round(app.dyn.rj_jcj(3,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(3,1,4),2)) num2str(round(app.dyn.rj_jcj(3,1,5),2)) num2str(round(app.dyn.rj_jcj(3,1,6),2)) num2str(round(app.dyn.rj_jcj(3,1,7),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36,36,36,36,36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)) num2str(round(app.dyn.Ij_cj(1,1,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,1,4),2)) num2str(round(app.dyn.Ij_cj(1,1,5),2)) num2str(round(app.dyn.Ij_cj(1,1,6),2)) num2str(round(app.dyn.Ij_cj(1,1,7),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)) num2str(round(app.dyn.Ij_cj(1,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,2,4),2)) num2str(round(app.dyn.Ij_cj(1,2,5),2)) num2str(round(app.dyn.Ij_cj(1,2,6),2)) num2str(round(app.dyn.Ij_cj(1,2,7),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)) num2str(round(app.dyn.Ij_cj(1,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,3,4),2)) num2str(round(app.dyn.Ij_cj(1,3,5),2)) num2str(round(app.dyn.Ij_cj(1,3,6),2)) num2str(round(app.dyn.Ij_cj(1,3,7),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)) num2str(round(app.dyn.Ij_cj(2,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,2,4),2)) num2str(round(app.dyn.Ij_cj(2,2,5),2)) num2str(round(app.dyn.Ij_cj(2,2,6),2)) num2str(round(app.dyn.Ij_cj(2,2,7),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)) num2str(round(app.dyn.Ij_cj(2,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,3,4),2)) num2str(round(app.dyn.Ij_cj(2,3,5),2)) num2str(round(app.dyn.Ij_cj(2,3,6),2)) num2str(round(app.dyn.Ij_cj(2,3,7),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2)) num2str(round(app.dyn.Ij_cj(3,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(3,3,4),2)) num2str(round(app.dyn.Ij_cj(3,3,5),2)) num2str(round(app.dyn.Ij_cj(3,3,6),2)) num2str(round(app.dyn.Ij_cj(3,3,7),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);
                app.Button_3.Value = app.kin.j_type(3,1);
                app.Button_4.Value = app.kin.j_type(4,1);
                app.Button_5.Value = app.kin.j_type(5,1);
                app.Button_6.Value = app.kin.j_type(6,1);
                app.Button_7.Value = app.kin.j_type(7,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "on";
                app.q_4ButtonGroup.Visible = "on";
                app.q_5ButtonGroup.Visible = "on";
                app.q_6ButtonGroup.Visible = "on";
                app.q_7ButtonGroup.Visible = "on";

            elseif app.kin.n == 6

                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3)))); ...
                             num2str(app.kin.a_j(4)*1e3) num2str(rad2deg(app.kin.alpha_j(4))) num2str(app.kin.d_j(4)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(4)))); ...
                             num2str(app.kin.a_j(5)*1e3) num2str(rad2deg(app.kin.alpha_j(5))) num2str(app.kin.d_j(5)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(5)))); ...
                             num2str(app.kin.a_j(6)*1e3) num2str(rad2deg(app.kin.alpha_j(6))) num2str(app.kin.d_j(6)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(6)))); ...
                             num2str(app.kin.a_j(7)*1e3) num2str(rad2deg(app.kin.alpha_j(7))) num2str(app.kin.d_j(7)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(7))))};

                LinkNames = {'1'; '2'; '3'; '4'; '5'; '6'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1)) num2str(pMax(2,1)) num2str(pMax(3,1)) num2str(pMax(4,1)) num2str(pMax(5,1)) num2str(pMax(6,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1)) num2str(pMin(2,1)) num2str(pMin(3,1)) num2str(pMin(4,1)) num2str(pMin(5,1)) num2str(pMin(6,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1)) num2str(vMag(2,1)) num2str(vMag(3,1)) num2str(vMag(4,1)) num2str(vMag(5,1)) num2str(vMag(6,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1)) num2str(aMag(2,1)) num2str(aMag(3,1)) num2str(aMag(4,1)) num2str(aMag(5,1)) num2str(aMag(6,1))};

                app.JointPosMaxTable.ColumnWidth = {40,40,40,40,40,40};
                app.JointPosMinTable.ColumnWidth = {40,40,40,40,40,40};
                app.JointVelMagTable.ColumnWidth = {40,40,40,40,40,40};
                app.JointAccMagTable.ColumnWidth = {40,40,40,40,40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36,36,36,36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2)) num2str(round(app.dyn.m_j(3),2)) num2str(round(app.dyn.m_j(4),2)) ...
                                        num2str(round(app.dyn.m_j(5),2)) num2str(round(app.dyn.m_j(6),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36,36,36,36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)) num2str(round(app.dyn.rj_jcj(1,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(1,1,4),2)) num2str(round(app.dyn.rj_jcj(1,1,5),2)) num2str(round(app.dyn.rj_jcj(1,1,6),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)) num2str(round(app.dyn.rj_jcj(2,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(2,1,4),2)) num2str(round(app.dyn.rj_jcj(2,1,5),2)) num2str(round(app.dyn.rj_jcj(2,1,6),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2)) num2str(round(app.dyn.rj_jcj(3,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(3,1,4),2)) num2str(round(app.dyn.rj_jcj(3,1,5),2)) num2str(round(app.dyn.rj_jcj(3,1,6),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36,36,36,36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)) num2str(round(app.dyn.Ij_cj(1,1,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,1,4),2)) num2str(round(app.dyn.Ij_cj(1,1,5),2)) num2str(round(app.dyn.Ij_cj(1,1,6),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)) num2str(round(app.dyn.Ij_cj(1,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,2,4),2)) num2str(round(app.dyn.Ij_cj(1,2,5),2)) num2str(round(app.dyn.Ij_cj(1,2,6),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)) num2str(round(app.dyn.Ij_cj(1,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,3,4),2)) num2str(round(app.dyn.Ij_cj(1,3,5),2)) num2str(round(app.dyn.Ij_cj(1,3,6),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)) num2str(round(app.dyn.Ij_cj(2,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,2,4),2)) num2str(round(app.dyn.Ij_cj(2,2,5),2)) num2str(round(app.dyn.Ij_cj(2,2,6),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)) num2str(round(app.dyn.Ij_cj(2,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,3,4),2)) num2str(round(app.dyn.Ij_cj(2,3,5),2)) num2str(round(app.dyn.Ij_cj(2,3,6),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2)) num2str(round(app.dyn.Ij_cj(3,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(3,3,4),2)) num2str(round(app.dyn.Ij_cj(3,3,5),2)) num2str(round(app.dyn.Ij_cj(3,3,6),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);
                app.Button_3.Value = app.kin.j_type(3,1);
                app.Button_4.Value = app.kin.j_type(4,1);
                app.Button_5.Value = app.kin.j_type(5,1);
                app.Button_6.Value = app.kin.j_type(6,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "on";
                app.q_4ButtonGroup.Visible = "on";
                app.q_5ButtonGroup.Visible = "on";
                app.q_6ButtonGroup.Visible = "on";
                app.q_7ButtonGroup.Visible = "off";

            elseif app.kin.n == 5

                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3)))); ...
                             num2str(app.kin.a_j(4)*1e3) num2str(rad2deg(app.kin.alpha_j(4))) num2str(app.kin.d_j(4)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(4)))); ...
                             num2str(app.kin.a_j(5)*1e3) num2str(rad2deg(app.kin.alpha_j(5))) num2str(app.kin.d_j(5)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(5)))); ...
                             num2str(app.kin.a_j(6)*1e3) num2str(rad2deg(app.kin.alpha_j(6))) num2str(app.kin.d_j(6)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(6))))};

                LinkNames = {'1'; '2'; '3'; '4'; '5'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1)) num2str(pMax(2,1)) num2str(pMax(3,1)) num2str(pMax(4,1)) num2str(pMax(5,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1)) num2str(pMin(2,1)) num2str(pMin(3,1)) num2str(pMin(4,1)) num2str(pMin(5,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1)) num2str(vMag(2,1)) num2str(vMag(3,1)) num2str(vMag(4,1)) num2str(vMag(5,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1)) num2str(aMag(2,1)) num2str(aMag(3,1)) num2str(aMag(4,1)) num2str(aMag(5,1))};

                app.JointPosMaxTable.ColumnWidth = {40,40,40,40,40};
                app.JointPosMinTable.ColumnWidth = {40,40,40,40,40};
                app.JointVelMagTable.ColumnWidth = {40,40,40,40,40};
                app.JointAccMagTable.ColumnWidth = {40,40,40,40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36,36,36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2)) num2str(round(app.dyn.m_j(3),2)) num2str(round(app.dyn.m_j(4),2)) ...
                                        num2str(round(app.dyn.m_j(5),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36,36,36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)) num2str(round(app.dyn.rj_jcj(1,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(1,1,4),2)) num2str(round(app.dyn.rj_jcj(1,1,5),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)) num2str(round(app.dyn.rj_jcj(2,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(2,1,4),2)) num2str(round(app.dyn.rj_jcj(2,1,5),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2)) num2str(round(app.dyn.rj_jcj(3,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(3,1,4),2)) num2str(round(app.dyn.rj_jcj(3,1,5),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36,36,36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)) num2str(round(app.dyn.Ij_cj(1,1,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,1,4),2)) num2str(round(app.dyn.Ij_cj(1,1,5),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)) num2str(round(app.dyn.Ij_cj(1,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,2,4),2)) num2str(round(app.dyn.Ij_cj(1,2,5),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)) num2str(round(app.dyn.Ij_cj(1,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,3,4),2)) num2str(round(app.dyn.Ij_cj(1,3,5),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)) num2str(round(app.dyn.Ij_cj(2,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,2,4),2)) num2str(round(app.dyn.Ij_cj(2,2,5),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)) num2str(round(app.dyn.Ij_cj(2,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,3,4),2)) num2str(round(app.dyn.Ij_cj(2,3,5),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2)) num2str(round(app.dyn.Ij_cj(3,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(3,3,4),2)) num2str(round(app.dyn.Ij_cj(3,3,5),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);
                app.Button_3.Value = app.kin.j_type(3,1);
                app.Button_4.Value = app.kin.j_type(4,1);
                app.Button_5.Value = app.kin.j_type(5,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "on";
                app.q_4ButtonGroup.Visible = "on";
                app.q_5ButtonGroup.Visible = "on";
                app.q_6ButtonGroup.Visible = "off";
                app.q_7ButtonGroup.Visible = "off";

            elseif app.kin.n == 4

                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3)))); ...
                             num2str(app.kin.a_j(4)*1e3) num2str(rad2deg(app.kin.alpha_j(4))) num2str(app.kin.d_j(4)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(4)))); ...
                             num2str(app.kin.a_j(5)*1e3) num2str(rad2deg(app.kin.alpha_j(5))) num2str(app.kin.d_j(5)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(5))))};

                LinkNames = {'1'; '2'; '3'; '4'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1)) num2str(pMax(2,1)) num2str(pMax(3,1)) num2str(pMax(4,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1)) num2str(pMin(2,1)) num2str(pMin(3,1)) num2str(pMin(4,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1)) num2str(vMag(2,1)) num2str(vMag(3,1)) num2str(vMag(4,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1)) num2str(aMag(2,1)) num2str(aMag(3,1)) num2str(aMag(4,1))};

                app.JointPosMaxTable.ColumnWidth = {40,40,40,40};
                app.JointPosMinTable.ColumnWidth = {40,40,40,40};
                app.JointVelMagTable.ColumnWidth = {40,40,40,40};
                app.JointAccMagTable.ColumnWidth = {40,40,40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36,36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2)) num2str(round(app.dyn.m_j(3),2)) num2str(round(app.dyn.m_j(4),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36,36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)) num2str(round(app.dyn.rj_jcj(1,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(1,1,4),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)) num2str(round(app.dyn.rj_jcj(2,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(2,1,4),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2)) num2str(round(app.dyn.rj_jcj(3,1,3),2)) ...
                                        num2str(round(app.dyn.rj_jcj(3,1,4),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36,36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)) num2str(round(app.dyn.Ij_cj(1,1,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,1,4),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)) num2str(round(app.dyn.Ij_cj(1,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,2,4),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)) num2str(round(app.dyn.Ij_cj(1,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(1,3,4),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)) num2str(round(app.dyn.Ij_cj(2,2,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,2,4),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)) num2str(round(app.dyn.Ij_cj(2,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(2,3,4),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2)) num2str(round(app.dyn.Ij_cj(3,3,3),2)) ...
                                                num2str(round(app.dyn.Ij_cj(3,3,4),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);
                app.Button_3.Value = app.kin.j_type(3,1);
                app.Button_4.Value = app.kin.j_type(4,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "on";
                app.q_4ButtonGroup.Visible = "on";
                app.q_5ButtonGroup.Visible = "off";
                app.q_6ButtonGroup.Visible = "off";
                app.q_7ButtonGroup.Visible = "off";

            elseif app.kin.n == 3

                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3)))); ...
                             num2str(app.kin.a_j(4)*1e3) num2str(rad2deg(app.kin.alpha_j(4))) num2str(app.kin.d_j(4)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(4))))};

                LinkNames = {'1'; '2'; '3'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1)) num2str(pMax(2,1)) num2str(pMax(3,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1)) num2str(pMin(2,1)) num2str(pMin(3,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1)) num2str(vMag(2,1)) num2str(vMag(3,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1)) num2str(aMag(2,1)) num2str(aMag(3,1))};

                app.JointPosMaxTable.ColumnWidth = {40,40,40};
                app.JointPosMinTable.ColumnWidth = {40,40,40};
                app.JointVelMagTable.ColumnWidth = {40,40,40};
                app.JointAccMagTable.ColumnWidth = {40,40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2)) num2str(round(app.dyn.m_j(3),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)) num2str(round(app.dyn.rj_jcj(1,1,3),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)) num2str(round(app.dyn.rj_jcj(2,1,3),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2)) num2str(round(app.dyn.rj_jcj(3,1,3),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)) num2str(round(app.dyn.Ij_cj(1,1,3),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)) num2str(round(app.dyn.Ij_cj(1,2,3),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)) num2str(round(app.dyn.Ij_cj(1,3,3),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)) num2str(round(app.dyn.Ij_cj(2,2,3),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)) num2str(round(app.dyn.Ij_cj(2,3,3),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2)) num2str(round(app.dyn.Ij_cj(3,3,3),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);
                app.Button_3.Value = app.kin.j_type(3,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "on";
                app.q_4ButtonGroup.Visible = "off";
                app.q_5ButtonGroup.Visible = "off";
                app.q_6ButtonGroup.Visible = "off";
                app.q_7ButtonGroup.Visible = "off";
            elseif app.kin.n == 2
                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2)))); ...
                             num2str(app.kin.a_j(3)*1e3) num2str(rad2deg(app.kin.alpha_j(3))) num2str(app.kin.d_j(3)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(3))))};

                LinkNames = {'1'; '2'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1)) num2str(pMax(2,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1)) num2str(pMin(2,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1)) num2str(vMag(2,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1)) num2str(aMag(2,1))};

                app.JointPosMaxTable.ColumnWidth = {40,40};
                app.JointPosMinTable.ColumnWidth = {40,40};
                app.JointVelMagTable.ColumnWidth = {40,40};
                app.JointAccMagTable.ColumnWidth = {40,40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36,36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2)) num2str(round(app.dyn.m_j(2),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36,36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)) num2str(round(app.dyn.rj_jcj(1,1,2),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)) num2str(round(app.dyn.rj_jcj(2,1,2),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2)) num2str(round(app.dyn.rj_jcj(3,1,2),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36,36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)) num2str(round(app.dyn.Ij_cj(1,1,2),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)) num2str(round(app.dyn.Ij_cj(1,2,2),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)) num2str(round(app.dyn.Ij_cj(1,3,2),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)) num2str(round(app.dyn.Ij_cj(2,2,2),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)) num2str(round(app.dyn.Ij_cj(2,3,2),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2)) num2str(round(app.dyn.Ij_cj(3,3,2),2))};

                app.Button.Value = app.kin.j_type(1,1);
                app.Button_2.Value = app.kin.j_type(2,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "on";
                app.q_3ButtonGroup.Visible = "off";
                app.q_4ButtonGroup.Visible = "off";
                app.q_5ButtonGroup.Visible = "off";
                app.q_6ButtonGroup.Visible = "off";
                app.q_7ButtonGroup.Visible = "off";
            else
                TableData = {num2str(app.kin.a_j(1)*1e3) num2str(rad2deg(app.kin.alpha_j(1))) num2str(app.kin.d_j(1)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(1)))); ...
                             num2str(app.kin.a_j(2)*1e3) num2str(rad2deg(app.kin.alpha_j(2))) num2str(app.kin.d_j(2)*1e3) num2str(round(rad2deg(app.kin.theta_O_j(2))))};

                LinkNames = {'1'; 'E'};

                app.JointPosMaxTable.Data = {num2str(pMax(1,1))};
                app.JointPosMinTable.Data = {num2str(pMin(1,1))};
                app.JointVelMagTable.Data = {num2str(vMag(1,1))};
                app.JointAccMagTable.Data = {num2str(aMag(1,1))};

                app.JointPosMaxTable.ColumnWidth = {40};
                app.JointPosMinTable.ColumnWidth = {40};
                app.JointVelMagTable.ColumnWidth = {40};
                app.JointAccMagTable.ColumnWidth = {40};

                app.MasskgLabel.Position = [7,294,254,22];
                app.MassTable.ColumnWidth = {36};
                app.MassTable.Position = [7,270,271,25];
                app.MassTable.Data = {num2str(round(app.dyn.m_j(1),2))};

                app.CenterofMassmLabel.Position = [7,244,254,22];
                app.CenterofMassTable.ColumnWidth = {36};
                app.CenterofMassTable.Position = [7,173,271,72];
                app.CenterofMassTable.Data = {num2str(round(app.dyn.rj_jcj(1,1,1),2)); ...
                                        num2str(round(app.dyn.rj_jcj(2,1,1),2)); ...
                                        num2str(round(app.dyn.rj_jcj(3,1,1),2))};

                app.UniqueElementsVectorofInertiaTensorsmLabel.Position = [7,147,254,22];
                app.CenterofMassTable_2.ColumnWidth = {36};
                app.CenterofMassTable_2.Position = [7,6,271,142];
                app.CenterofMassTable_2.Data = {num2str(round(app.dyn.Ij_cj(1,1,1),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,2,1),2)); ...
                                                num2str(round(app.dyn.Ij_cj(1,3,1),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,2,1),2)); ...
                                                num2str(round(app.dyn.Ij_cj(2,3,1),2)); ...
                                                num2str(round(app.dyn.Ij_cj(3,3,1),2))};

                app.Button.Value = app.kin.j_type(1,1);

                app.q_1ButtonGroup.Visible = "on";
                app.q_2ButtonGroup.Visible = "off";
                app.q_3ButtonGroup.Visible = "off";
                app.q_4ButtonGroup.Visible = "off";
                app.q_5ButtonGroup.Visible = "off";
                app.q_6ButtonGroup.Visible = "off";
                app.q_7ButtonGroup.Visible = "off";
            end

            T = table(TableData,'RowNames',LinkNames);
            C1 = table2cell(T);
            C = [T.Properties.RowNames, vertcat(C1{:})];
            app.DHTable.Data = C;
            app.DHTable.ColumnName = {''; 'a_j'; 'α_j'; 'd_j'; 'θ_j'};
            app.DHTable.RowName = [];
            app.DHTable.ColumnWidth = {15,55,55,55,55};
            colStyle = uistyle('BackgroundColor',app.DHTable.BackgroundColor(2,:),'FontWeight','bold'); 
            addStyle(app.DHTable,colStyle,'column',1)

            addStyle(app.DHTable,uistyle('HorizontalAlignment','right'),'table','');

            updateControlParams(app)
        end
        
        function updateEndEffectorModel(app)
            
            if app.robot_model ~= 0
                % app.DHTable.Enable = "off";
                if app.robot_model == 4 || app.robot_model == 5
                    app.EndEffectorDropDown.Items = {'None', 'F/T Sensor', 'F/T Sensor + Gripper', 'F/T Sensor + Ball&Spring'};
                    if app.grip == 1
                        app.EndEffectorDropDown.Value = 'F/T Sensor + Gripper';
                        app.ee_att = 2;
                        app.grip = 0;
    
                        updateRobotModel(app)
                    end
                else
                    app.EndEffectorDropDown.Items = {'None', 'Gripper'};
                    if app.exceeding == 1
                        app.EndEffectorDropDown.Value = 'Gripper';
                        app.ee_att = 1;
                        app.exceeding = 0;
    
                        updateRobotModel(app)
                    end
                end
                
                if app.ee_att == 1
                    if app.robot_model == 3
                        app.qEIni = app.kin.qELim(1);
                        app.qEDes = app.kin.qELim(1);
                    elseif app.robot_model == 1
                        app.qEIni = app.kin.qELim(2);
                        app.qEDes = app.kin.qELim(2);
                    end
                    if (app.robot_model ~= 4 && app.robot_model ~= 5)
                        app.GripperInitialLabel.Visible = 'on';
                        app.GripperInitialSlider.Visible = 'on';
                        app.GripperInitialSpinner.Visible = 'on';
                        app.GripperFinalLabel.Visible = 'on';
                        app.GripperFinalSlider.Visible = 'on';
                        app.GripperFinalSpinner.Visible = 'on';
                        app.GripperTaskInitialLabel.Visible = 'on';
                        app.GripperTaskInitialSlider.Visible = 'on';
                        app.GripperTaskInitialSpinner.Visible = 'on';
                        app.GripperTaskFinalLabel.Visible = 'on';
                        app.GripperTaskFinalSlider.Visible = 'on';
                        app.GripperTaskFinalSpinner.Visible = 'on';
                    else
                        app.GripperInitialLabel.Visible = 'off';
                        app.GripperInitialSlider.Visible = 'off';
                        app.GripperInitialSpinner.Visible = 'off';
                        app.GripperFinalLabel.Visible = 'off';
                        app.GripperFinalSlider.Visible = 'off';
                        app.GripperFinalSpinner.Visible = 'off';
                        app.GripperTaskInitialLabel.Visible = 'off';
                        app.GripperTaskInitialSlider.Visible = 'off';
                        app.GripperTaskInitialSpinner.Visible = 'off';
                        app.GripperTaskFinalLabel.Visible = 'off';
                        app.GripperTaskFinalSlider.Visible = 'off';
                        app.GripperTaskFinalSpinner.Visible = 'off';
                    end
                    if (app.robot_model == 1 || app.robot_model == 3)
                        app.GripperInitialLabel.Enable = 'on';
                        app.GripperInitialSlider.Enable = 'on';
                        app.GripperInitialSpinner.Enable = 'on';
                        app.GripperFinalLabel.Enable = 'on';
                        app.GripperFinalSlider.Enable = 'on';
                        app.GripperFinalSpinner.Enable = 'on';
                        app.GripperTaskInitialLabel.Enable = 'on';
                        app.GripperTaskInitialSlider.Enable = 'on';
                        app.GripperTaskInitialSpinner.Enable = 'on';
                        app.GripperTaskFinalLabel.Enable = 'on';
                        app.GripperTaskFinalSlider.Enable = 'on';
                        app.GripperTaskFinalSpinner.Enable = 'on';
                    else
                        app.GripperInitialLabel.Enable = 'off';
                        app.GripperInitialSlider.Enable = 'off';
                        app.GripperInitialSpinner.Enable = 'off';
                        app.GripperFinalLabel.Enable = 'off';
                        app.GripperFinalSlider.Enable = 'off';
                        app.GripperFinalSpinner.Enable = 'off';
                        app.GripperTaskInitialLabel.Enable = 'off';
                        app.GripperTaskInitialSlider.Enable = 'off';
                        app.GripperTaskInitialSpinner.Enable = 'off';
                        app.GripperTaskFinalLabel.Enable = 'off';
                        app.GripperTaskFinalSlider.Enable = 'off';
                        app.GripperTaskFinalSpinner.Enable = 'off';
                    end
                elseif app.ee_att == 2
                    app.GripperInitialLabel.Visible = 'on';
                    app.GripperInitialSlider.Visible = 'on';
                    app.GripperInitialSpinner.Visible = 'on';
                    app.GripperFinalLabel.Visible = 'on';
                    app.GripperFinalSlider.Visible = 'on';
                    app.GripperFinalSpinner.Visible = 'on';
                    app.GripperInitialLabel.Enable = 'off';
                    app.GripperInitialSlider.Enable = 'off';
                    app.GripperInitialSpinner.Enable = 'off';
                    app.GripperFinalLabel.Enable = 'off';
                    app.GripperFinalSlider.Enable = 'off';
                    app.GripperFinalSpinner.Visible = 'off';
                    app.GripperTaskInitialLabel.Visible = 'on';
                    app.GripperTaskInitialSlider.Visible = 'on';
                    app.GripperTaskInitialSpinner.Visible = 'on';
                    app.GripperTaskFinalLabel.Visible = 'on';
                    app.GripperTaskFinalSlider.Visible = 'on';
                    app.GripperTaskFinalSpinner.Visible = 'on';
                    app.GripperTaskInitialLabel.Enable = 'off';
                    app.GripperTaskInitialSlider.Enable = 'off';
                    app.GripperTaskInitialSpinner.Enable = 'off';
                    app.GripperTaskFinalLabel.Enable = 'off';
                    app.GripperTaskFinalSlider.Enable = 'off';
                    app.GripperTaskFinalSpinner.Visible = 'off';
                else
                    app.GripperInitialLabel.Visible = 'off';
                    app.GripperInitialSlider.Visible = 'off';
                    app.GripperInitialSpinner.Visible = 'off';
                    app.GripperFinalLabel.Visible = 'off';
                    app.GripperFinalSlider.Visible = 'off';
                    app.GripperFinalSpinner.Visible = 'off';
                    app.GripperInitialLabel.Enable = 'off';
                    app.GripperInitialSlider.Enable = 'off';
                    app.GripperInitialSpinner.Enable = 'off';
                    app.GripperFinalLabel.Enable = 'off';
                    app.GripperFinalSlider.Enable = 'off';
                    app.GripperFinalSpinner.Enable = 'off';
                    app.GripperTaskInitialLabel.Visible = 'off';
                    app.GripperTaskInitialSlider.Visible = 'off';
                    app.GripperTaskInitialSpinner.Visible = 'off';
                    app.GripperTaskFinalLabel.Visible = 'off';
                    app.GripperTaskFinalSlider.Visible = 'off';
                    app.GripperTaskFinalSpinner.Visible = 'off';
                    app.GripperTaskInitialLabel.Enable = 'off';
                    app.GripperTaskInitialSlider.Enable = 'off';
                    app.GripperTaskInitialSpinner.Enable = 'off';
                    app.GripperTaskFinalLabel.Enable = 'off';
                    app.GripperTaskFinalSlider.Enable = 'off';
                    app.GripperTaskFinalSpinner.Enable = 'off';
                end
            else
                app.GripperInitialSpinner.Visible = 'off';
                app.GripperInitialSlider.Visible = 'off';
                app.GripperInitialLabel.Visible = 'off';
                app.GripperInitialSpinner.Visible = 'off';
                app.GripperFinalSlider.Visible = 'off';
                app.GripperFinalLabel.Visible = 'off';
                app.GripperFinalSpinner.Visible = 'off';
                app.GripperTaskInitialSpinner.Visible = 'off';
                app.GripperTaskInitialSlider.Visible = 'off';
                app.GripperTaskInitialLabel.Visible = 'off';
                app.GripperTaskInitialSpinner.Visible = 'off';
                app.GripperTaskFinalSlider.Visible = 'off';
                app.GripperTaskFinalLabel.Visible = 'off';
                app.GripperTaskFinalSpinner.Visible = 'off';
            end

        end

        function resetJointValues(app)

            app.ini.q_pos = [];
            app.fin.q_pos = [];
            app.ini.q_pos = zeros(app.kin.n,1);
            app.fin.q_pos = zeros(app.kin.n,1);
            for j = 1:app.kin.n
                app.ini.q_pos(j,1) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
                app.fin.q_pos(j,1) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
            end

        end

        function updateSliderSpinnerValues(app)

            if app.kin.j_type(1,1) == 1
                app.Axis1InitialLabel.Text      = "Joint 1 [deg]  ";
                app.Axis1InitialSlider.Limits   = rad2deg(app.kin.q_posLim(1,:));
                app.Axis1InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(1,:));
                app.Axis1InitialSlider.Value    = rad2deg(app.ini.q_pos(1));
                app.Axis1InitialSpinner.Value   = rad2deg(app.ini.q_pos(1));
                app.Axis1FinalLabel.Text        = "Joint 1 [deg]  ";
                app.Axis1FinalSlider.Limits     = rad2deg(app.kin.q_posLim(1,:));
                app.Axis1FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(1,:));
                app.Axis1FinalSlider.Value      = rad2deg(app.fin.q_pos(1));
                app.Axis1FinalSpinner.Value     = rad2deg(app.fin.q_pos(1));
            else
                app.Axis1InitialLabel.Text      = "Joint 1 [mm]  ";
                app.Axis1InitialSlider.Limits   = 1e3*(app.kin.q_posLim(1,:));
                app.Axis1InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(1,:));
                app.Axis1InitialSlider.Value    = 1e3*(app.ini.q_pos(1));
                app.Axis1InitialSpinner.Value   = 1e3*(app.ini.q_pos(1));
                app.Axis1FinalLabel.Text        = "Joint 1 [mm]  ";
                app.Axis1FinalSlider.Limits     = 1e3*(app.kin.q_posLim(1,:));
                app.Axis1FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(1,:));
                app.Axis1FinalSlider.Value      = 1e3*(app.fin.q_pos(1));
                app.Axis1FinalSpinner.Value     = 1e3*(app.fin.q_pos(1));
            end
            if app.kin.n > 1
                if app.kin.j_type(2,1) == 1
                    app.Axis2InitialLabel.Text      = "Joint 2 [deg]  ";
                    app.Axis2InitialSlider.Limits   = rad2deg(app.kin.q_posLim(2,:));
                    app.Axis2InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(2,:));
                    app.Axis2InitialSlider.Value    = rad2deg(app.ini.q_pos(2));
                    app.Axis2InitialSpinner.Value   = rad2deg(app.ini.q_pos(2));
                    app.Axis2FinalLabel.Text        = "Joint 2 [deg]  ";
                    app.Axis2FinalSlider.Limits     = rad2deg(app.kin.q_posLim(2,:));
                    app.Axis2FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(2,:));
                    app.Axis2FinalSlider.Value      = rad2deg(app.fin.q_pos(2));
                    app.Axis2FinalSpinner.Value     = rad2deg(app.fin.q_pos(2));
                else
                    app.Axis2InitialLabel.Text      = "Joint 2 [mm]  ";
                    app.Axis2InitialSlider.Limits   = 1e3*(app.kin.q_posLim(2,:));
                    app.Axis2InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(2,:));
                    app.Axis2InitialSlider.Value    = 1e3*(app.ini.q_pos(2));
                    app.Axis2InitialSpinner.Value   = 1e3*(app.ini.q_pos(2));
                    app.Axis2FinalLabel.Text        = "Joint 2 [mm]  ";
                    app.Axis2FinalSlider.Limits     = 1e3*(app.kin.q_posLim(2,:));
                    app.Axis2FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(2,:));
                    app.Axis2FinalSlider.Value      = 1e3*(app.fin.q_pos(2));
                    app.Axis2FinalSpinner.Value     = 1e3*(app.fin.q_pos(2));
                end
                app.Axis2InitialLabel.Visible = 'on';
                app.Axis2InitialSlider.Visible = 'on';
                app.Axis2InitialSpinner.Visible = 'on';
                app.Axis2FinalLabel.Visible = 'on';
                app.Axis2FinalSlider.Visible = 'on';
                app.Axis2FinalSpinner.Visible = 'on';
            else
                app.Axis2InitialLabel.Visible = 'off';
                app.Axis2InitialSlider.Visible = 'off';
                app.Axis2InitialSpinner.Visible = 'off';
                app.Axis2FinalLabel.Visible = 'off';
                app.Axis2FinalSlider.Visible = 'off';
                app.Axis2FinalSpinner.Visible = 'off';
            end
            if app.kin.n > 2
                if app.kin.j_type(3,1) == 1
                    app.Axis3InitialLabel.Text      = "Joint 3 [deg]  ";
                    app.Axis3InitialSlider.Limits   = rad2deg(app.kin.q_posLim(3,:));
                    app.Axis3InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(3,:));
                    app.Axis3InitialSlider.Value    = rad2deg(app.ini.q_pos(3));
                    app.Axis3InitialSpinner.Value   = rad2deg(app.ini.q_pos(3));
                    app.Axis3FinalLabel.Text        = "Joint 3 [deg]  ";
                    app.Axis3FinalSlider.Limits     = rad2deg(app.kin.q_posLim(3,:));
                    app.Axis3FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(3,:));
                    app.Axis3FinalSlider.Value      = rad2deg(app.fin.q_pos(3));
                    app.Axis3FinalSpinner.Value     = rad2deg(app.fin.q_pos(3));
                else
                    app.Axis3InitialLabel.Text      = "Joint 3 [mm]  ";
                    app.Axis3InitialSlider.Limits   = 1e3*(app.kin.q_posLim(3,:));
                    app.Axis3InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(3,:));
                    app.Axis3InitialSlider.Value    = 1e3*(app.ini.q_pos(3));
                    app.Axis3InitialSpinner.Value   = 1e3*(app.ini.q_pos(3));
                    app.Axis3FinalLabel.Text        = "Joint 3 [mm]  ";
                    app.Axis3FinalSlider.Limits     = 1e3*(app.kin.q_posLim(3,:));
                    app.Axis3FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(3,:));
                    app.Axis3FinalSlider.Value      = 1e3*(app.fin.q_pos(3));
                    app.Axis3FinalSpinner.Value     = 1e3*(app.fin.q_pos(3));
                end
                app.Axis3InitialLabel.Visible = 'on';
                app.Axis3InitialSlider.Visible = 'on';
                app.Axis3InitialSpinner.Visible = 'on';
                app.Axis3FinalLabel.Visible = 'on';
                app.Axis3FinalSlider.Visible = 'on';
                app.Axis3FinalSpinner.Visible = 'on';
            else
                app.Axis3InitialLabel.Visible = 'off';
                app.Axis3InitialSlider.Visible = 'off';
                app.Axis3InitialSpinner.Visible = 'off';
                app.Axis3FinalLabel.Visible = 'off';
                app.Axis3FinalSlider.Visible = 'off';
                app.Axis3FinalSpinner.Visible = 'off';
            end
            if app.kin.n > 3
                if app.kin.j_type(4,1) == 1
                    app.Axis4InitialLabel.Text      = "Joint 4 [deg]  ";
                    app.Axis4InitialSlider.Limits   = rad2deg(app.kin.q_posLim(4,:));
                    app.Axis4InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(4,:));
                    app.Axis4InitialSlider.Value    = rad2deg(app.ini.q_pos(4));
                    app.Axis4InitialSpinner.Value   = rad2deg(app.ini.q_pos(4));
                    app.Axis4FinalLabel.Text        = "Joint 4 [deg]  ";
                    app.Axis4FinalSlider.Limits     = rad2deg(app.kin.q_posLim(4,:));
                    app.Axis4FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(4,:));
                    app.Axis4FinalSlider.Value      = rad2deg(app.fin.q_pos(4));
                    app.Axis4FinalSpinner.Value     = rad2deg(app.fin.q_pos(4));
                else
                    app.Axis4InitialLabel.Text      = "Joint 4 [mm]  ";
                    app.Axis4InitialSlider.Limits   = 1e3*(app.kin.q_posLim(4,:));
                    app.Axis4InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(4,:));
                    app.Axis4InitialSlider.Value    = 1e3*(app.ini.q_pos(4));
                    app.Axis4InitialSpinner.Value   = 1e3*(app.ini.q_pos(4));
                    app.Axis4FinalLabel.Text        = "Joint 4 [mm]  ";
                    app.Axis4FinalSlider.Limits     = 1e3*(app.kin.q_posLim(4,:));
                    app.Axis4FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(4,:));
                    app.Axis4FinalSlider.Value      = 1e3*(app.fin.q_pos(4));
                    app.Axis4FinalSpinner.Value     = 1e3*(app.fin.q_pos(4));
                end
                app.Axis4InitialLabel.Visible = 'on';
                app.Axis4InitialSlider.Visible = 'on';
                app.Axis4InitialSpinner.Visible = 'on';
                app.Axis4FinalLabel.Visible = 'on';
                app.Axis4FinalSlider.Visible = 'on';
                app.Axis4FinalSpinner.Visible = 'on';
            else
                app.Axis4InitialLabel.Visible = 'off';
                app.Axis4InitialSlider.Visible = 'off';
                app.Axis4InitialSpinner.Visible = 'off';
                app.Axis4FinalLabel.Visible = 'off';
                app.Axis4FinalSlider.Visible = 'off';
                app.Axis4FinalSpinner.Visible = 'off';
            end
            if app.kin.n > 4
                if app.kin.j_type(5,1) == 1
                    app.Axis5InitialLabel.Text      = "Joint 5 [deg]  ";
                    app.Axis5InitialSlider.Limits   = rad2deg(app.kin.q_posLim(5,:));
                    app.Axis5InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(5,:));
                    app.Axis5InitialSlider.Value    = rad2deg(app.ini.q_pos(5));
                    app.Axis5InitialSpinner.Value   = rad2deg(app.ini.q_pos(5));
                    app.Axis5FinalLabel.Text        = "Joint 5 [deg]  ";
                    app.Axis5FinalSlider.Limits     = rad2deg(app.kin.q_posLim(5,:));
                    app.Axis5FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(5,:));
                    app.Axis5FinalSlider.Value      = rad2deg(app.fin.q_pos(5));
                    app.Axis5FinalSpinner.Value     = rad2deg(app.fin.q_pos(5));
                else
                    app.Axis5InitialLabel.Text      = "Joint 5 [mm]  ";
                    app.Axis5InitialSlider.Limits   = 1e3*(app.kin.q_posLim(5,:));
                    app.Axis5InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(5,:));
                    app.Axis5InitialSlider.Value    = 1e3*(app.ini.q_pos(5));
                    app.Axis5InitialSpinner.Value   = 1e3*(app.ini.q_pos(5));
                    app.Axis5FinalLabel.Text        = "Joint 5 [mm]  ";
                    app.Axis5FinalSlider.Limits     = 1e3*(app.kin.q_posLim(5,:));
                    app.Axis5FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(5,:));
                    app.Axis5FinalSlider.Value      = 1e3*(app.fin.q_pos(5));
                    app.Axis5FinalSpinner.Value     = 1e3*(app.fin.q_pos(5));
                end
                app.Axis5InitialLabel.Visible = 'on';
                app.Axis5InitialSlider.Visible = 'on';
                app.Axis5InitialSpinner.Visible = 'on';
                app.Axis5FinalLabel.Visible = 'on';
                app.Axis5FinalSlider.Visible = 'on';
                app.Axis5FinalSpinner.Visible = 'on';
            else
                app.Axis5InitialLabel.Visible = 'off';
                app.Axis5InitialSlider.Visible = 'off';
                app.Axis5InitialSpinner.Visible = 'off';
                app.Axis5FinalLabel.Visible = 'off';
                app.Axis5FinalSlider.Visible = 'off';
                app.Axis5FinalSpinner.Visible = 'off';
            end
            if app.kin.n > 5
                if app.kin.j_type(6,1) == 1
                    app.Axis6InitialLabel.Text      = "Joint 6 [deg]  ";
                    app.Axis6InitialSlider.Limits   = rad2deg(app.kin.q_posLim(6 ,:));
                    app.Axis6InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(6 ,:));
                    app.Axis6InitialSlider.Value    = rad2deg(app.ini.q_pos(6));
                    app.Axis6InitialSpinner.Value   = rad2deg(app.ini.q_pos(6));
                    app.Axis6FinalLabel.Text        = "Joint 6 [deg]  ";
                    app.Axis6FinalSlider.Limits     = rad2deg(app.kin.q_posLim(6 ,:));
                    app.Axis6FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(6 ,:));
                    app.Axis6FinalSlider.Value      = rad2deg(app.fin.q_pos(6));
                    app.Axis6FinalSpinner.Value     = rad2deg(app.fin.q_pos(6));
                else
                    app.Axis6InitialLabel.Text      = "Joint 6 [mm]  ";
                    app.Axis6InitialSlider.Limits   = 1e3*(app.kin.q_posLim(6,:));
                    app.Axis6InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(6,:));
                    app.Axis6InitialSlider.Value    = 1e3*(app.ini.q_pos(6));
                    app.Axis6InitialSpinner.Value   = 1e3*(app.ini.q_pos(6));
                    app.Axis6FinalLabel.Text        = "Joint 6 [mm]  ";
                    app.Axis6FinalSlider.Limits     = 1e3*(app.kin.q_posLim(6,:));
                    app.Axis6FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(6,:));
                    app.Axis6FinalSlider.Value      = 1e3*(app.fin.q_pos(6));
                    app.Axis6FinalSpinner.Value     = 1e3*(app.fin.q_pos(6));
                end
                app.Axis6InitialLabel.Visible = 'on';
                app.Axis6InitialSlider.Visible = 'on';
                app.Axis6InitialSpinner.Visible = 'on';
                app.Axis6FinalLabel.Visible = 'on';
                app.Axis6FinalSlider.Visible = 'on';
                app.Axis6FinalSpinner.Visible = 'on';
            else
                app.Axis6InitialLabel.Visible = 'off';
                app.Axis6InitialSlider.Visible = 'off';
                app.Axis6InitialSpinner.Visible = 'off';
                app.Axis6FinalLabel.Visible = 'off';
                app.Axis6FinalSlider.Visible = 'off';
                app.Axis6FinalSpinner.Visible = 'off';
            end
            if app.kin.n > 6
                if app.kin.j_type(7,1) == 1
                    app.Axis7InitialLabel.Text      = "Joint 7 [deg]  ";
                    app.Axis7InitialSlider.Limits   = rad2deg(app.kin.q_posLim(7 ,:));
                    app.Axis7InitialSpinner.Limits  = rad2deg(app.kin.q_posLim(7 ,:));
                    app.Axis7InitialSlider.Value    = rad2deg(app.ini.q_pos(7));
                    app.Axis7InitialSpinner.Value   = rad2deg(app.ini.q_pos(7));
                    app.Axis7FinalLabel.Text        = "Joint 7 [deg]  ";
                    app.Axis7FinalSlider.Limits     = rad2deg(app.kin.q_posLim(7 ,:));
                    app.Axis7FinalSpinner.Limits    = rad2deg(app.kin.q_posLim(7 ,:));
                    app.Axis7FinalSlider.Value      = rad2deg(app.fin.q_pos(7));
                    app.Axis7FinalSpinner.Value     = rad2deg(app.fin.q_pos(7));
                else
                    app.Axis7InitialLabel.Text      = "Joint 7 [mm]  ";
                    app.Axis7InitialSlider.Limits   = 1e3*(app.kin.q_posLim(7,:));
                    app.Axis7InitialSpinner.Limits  = 1e3*(app.kin.q_posLim(7,:));
                    app.Axis7InitialSlider.Value    = 1e3*(app.ini.q_pos(7));
                    app.Axis7InitialSpinner.Value   = 1e3*(app.ini.q_pos(7));
                    app.Axis7FinalLabel.Text        = "Joint 7 [mm]  ";
                    app.Axis7FinalSlider.Limits     = 1e3*(app.kin.q_posLim(7,:));
                    app.Axis7FinalSpinner.Limits    = 1e3*(app.kin.q_posLim(7,:));
                    app.Axis7FinalSlider.Value      = 1e3*(app.fin.q_pos(7));
                    app.Axis7FinalSpinner.Value     = 1e3*(app.fin.q_pos(7));
                end
                app.Axis7InitialLabel.Visible = 'on';
                app.Axis7InitialSlider.Visible = 'on';
                app.Axis7InitialSpinner.Visible = 'on';
                app.Axis7FinalLabel.Visible = 'on';
                app.Axis7FinalSlider.Visible = 'on';
                app.Axis7FinalSpinner.Visible = 'on';
            else
                app.Axis7InitialLabel.Visible = 'off';
                app.Axis7InitialSlider.Visible = 'off';
                app.Axis7InitialSpinner.Visible = 'off';
                app.Axis7FinalLabel.Visible = 'off';
                app.Axis7FinalSlider.Visible = 'off';
                app.Axis7FinalSpinner.Visible = 'off';
            end

            if app.ee_att == 1
                if app.robot_model == 1
                    app.GripperInitialLabel.Text        = "Gripper [mm]  ";
                    app.GripperInitialSlider.Limits     = app.kin.qELim*1e3;
                    app.GripperInitialSpinner.Limits    = app.kin.qELim*1e3;
                    app.GripperInitialSlider.Value      = app.qEIni*1e3;
                    app.GripperInitialSpinner.Value     = app.qEIni*1e3;
                    app.GripperFinalLabel.Text          = "Gripper [mm]  ";
                    app.GripperFinalSlider.Limits       = app.kin.qELim*1e3;
                    app.GripperFinalSpinner.Limits      = app.kin.qELim*1e3;
                    app.GripperFinalSlider.Value        = app.qEDes*1e3;
                    app.GripperFinalSpinner.Value       = app.qEDes*1e3;
                elseif app.robot_model == 3
                    app.GripperInitialLabel.Text        = "Gripper [deg]  ";
                    app.GripperInitialSlider.Limits     = rad2deg(app.kin.qELim);
                    app.GripperInitialSpinner.Limits    = rad2deg(app.kin.qELim);
                    app.GripperInitialSlider.Value      = rad2deg(app.kin.qELim(1));
                    app.GripperInitialSpinner.Value     = rad2deg(app.kin.qELim(1));
                    app.GripperFinalLabel.Text          = "Gripper [deg]  ";
                    app.GripperFinalSlider.Limits       = rad2deg(app.kin.qELim);
                    app.GripperFinalSpinner.Limits      = rad2deg(app.kin.qELim);
                    app.GripperFinalSlider.Value        = rad2deg(app.kin.qELim(1));
                    app.GripperFinalSpinner.Value       = rad2deg(app.kin.qELim(1));
                end
            end

        end
        
        function updateTaskInputValues(app)

            app.ini.Ti = zeros(4,4,app.kin.n+2);
            app.ini.Ti(:,:,1) = eye(4);
            app.fin.Ti = zeros(4,4,app.kin.n+2);
            app.fin.Ti(:,:,1) = eye(4);
            for j = 1:app.kin.n
                app.ini.Ti(:,:,j+1) = app.ini.Ti(:,:,j)*SO3R3_SE3(getRi_j(app.kin.alpha_j(j),app.kin.theta_O_j(j)+app.ini.q_pos(j)),app.kin.ri_j(:,:,j));
                app.fin.Ti(:,:,j+1) = app.fin.Ti(:,:,j)*SO3R3_SE3(getRi_j(app.kin.alpha_j(j),app.kin.theta_O_j(j)+app.fin.q_pos(j)),app.kin.ri_j(:,:,j));
            end
            app.ini.Ti(:,:,app.kin.n+2) = app.ini.Ti(:,:,app.kin.n+1)*SO3R3_SE3(getRi_j(app.kin.alpha_j(app.kin.n+1),app.kin.theta_O_j(app.kin.n+1)),app.kin.ri_j(:,:,app.kin.n+1));
            app.fin.Ti(:,:,app.kin.n+2) = app.fin.Ti(:,:,app.kin.n+1)*SO3R3_SE3(getRi_j(app.kin.alpha_j(app.kin.n+1),app.kin.theta_O_j(app.kin.n+1)),app.kin.ri_j(:,:,app.kin.n+1));
            [app.ini.Re,app.ini.t_pos] = SE3_SO3R3(app.ini.Ti(:,:,app.kin.n+2));
            [app.fin.Re,app.fin.t_pos] = SE3_SO3R3(app.fin.Ti(:,:,app.kin.n+2));

            app.ini.r_pos = getEulerPosVec(app.ini.Re,app.eulerSet);
            app.fin.r_pos = getEulerPosVec(app.ini.Re,app.eulerSet);

            app.XAxisInitialSlider.Limits   = 1e3*(app.kin.t_posLim(1,:));
            app.XAxisInitialSpinner.Limits  = 1e3*(app.kin.t_posLim(1,:));
            app.XAxisInitialSlider.Value    = 1e3*(app.ini.t_pos(1));
            app.XAxisInitialSpinner.Value   = 1e3*(app.ini.t_pos(1));
            app.XAxisFinalSlider.Limits     = 1e3*(app.kin.t_posLim(1,:));
            app.XAxisFinalSpinner.Limits    = 1e3*(app.kin.t_posLim(1,:));
            app.XAxisFinalSlider.Value      = 1e3*(app.fin.t_pos(1));
            app.XAxisFinalSpinner.Value     = 1e3*(app.fin.t_pos(1));

            app.YAxisInitialSlider.Limits   = 1e3*(app.kin.t_posLim(2,:));
            app.YAxisInitialSpinner.Limits  = 1e3*(app.kin.t_posLim(2,:));
            app.YAxisInitialSlider.Value    = 1e3*(app.ini.t_pos(2));
            app.YAxisInitialSpinner.Value   = 1e3*(app.ini.t_pos(2));
            app.YAxisFinalSlider.Limits     = 1e3*(app.kin.t_posLim(2,:));
            app.YAxisFinalSpinner.Limits    = 1e3*(app.kin.t_posLim(2,:));
            app.YAxisFinalSlider.Value      = 1e3*(app.fin.t_pos(2));
            app.YAxisFinalSpinner.Value     = 1e3*(app.fin.t_pos(2));

            app.ZAxisInitialSlider.Limits   = 1e3*(app.kin.t_posLim(3,:));
            app.ZAxisInitialSpinner.Limits  = 1e3*(app.kin.t_posLim(3,:));
            app.ZAxisInitialSlider.Value    = 1e3*(app.ini.t_pos(3));
            app.ZAxisInitialSpinner.Value   = 1e3*(app.ini.t_pos(3));
            app.ZAxisFinalSlider.Limits     = 1e3*(app.kin.t_posLim(3,:));
            app.ZAxisFinalSpinner.Limits    = 1e3*(app.kin.t_posLim(3,:));
            app.ZAxisFinalSlider.Value      = 1e3*(app.fin.t_pos(3));
            app.ZAxisFinalSpinner.Value     = 1e3*(app.fin.t_pos(3));

            app.PhiInitialSlider.Limits   = rad2deg(app.kin.r_posLim(1,:));
            app.PhiInitialSpinner.Limits  = rad2deg(app.kin.r_posLim(1,:));
            app.PhiInitialSlider.Value    = rad2deg(app.ini.r_pos(1));
            app.PhiInitialSpinner.Value   = rad2deg(app.ini.r_pos(1));
            app.PhiFinalSlider.Limits     = rad2deg(app.kin.r_posLim(1,:));
            app.PhiFinalSpinner.Limits    = rad2deg(app.kin.r_posLim(1,:));
            app.PhiFinalSlider.Value      = rad2deg(app.fin.r_pos(1));
            app.PhiFinalSpinner.Value     = rad2deg(app.fin.r_pos(1));

            app.ThetaInitialSlider.Limits   = rad2deg(app.kin.r_posLim(2,:));
            app.ThetaInitialSpinner.Limits  = rad2deg(app.kin.r_posLim(2,:));
            app.ThetaInitialSlider.Value    = rad2deg(app.ini.r_pos(2));
            app.ThetaInitialSpinner.Value   = rad2deg(app.ini.r_pos(2));
            app.ThetaFinalSlider.Limits     = rad2deg(app.kin.r_posLim(2,:));
            app.ThetaFinalSpinner.Limits    = rad2deg(app.kin.r_posLim(2,:));
            app.ThetaFinalSlider.Value      = rad2deg(app.fin.r_pos(2));
            app.ThetaFinalSpinner.Value     = rad2deg(app.fin.r_pos(2));

            app.PsiInitialSlider.Limits   = rad2deg(app.kin.r_posLim(3,:));
            app.PsiInitialSpinner.Limits  = rad2deg(app.kin.r_posLim(3,:));
            app.PsiInitialSlider.Value    = rad2deg(app.ini.r_pos(3));
            app.PsiInitialSpinner.Value   = rad2deg(app.ini.r_pos(3));
            app.PsiFinalSlider.Limits     = rad2deg(app.kin.r_posLim(3,:));
            app.PsiFinalSpinner.Limits    = rad2deg(app.kin.r_posLim(3,:));
            app.PsiFinalSlider.Value      = rad2deg(app.fin.r_pos(3));
            app.PsiFinalSpinner.Value     = rad2deg(app.fin.r_pos(3));

            if app.ee_att == 1
                if app.robot_model == 1
                    app.GripperTaskInitialLabel.Text        = "Gripper [mm]  ";
                    app.GripperTaskInitialSlider.Limits     = app.kin.qELim*1e3;
                    app.GripperTaskInitialSpinner.Limits    = app.kin.qELim*1e3;
                    app.GripperTaskInitialSlider.Value      = app.qEIni*1e3;
                    app.GripperTaskInitialSpinner.Value     = app.qEIni*1e3;
                    app.GripperTaskFinalLabel.Text          = "Gripper [mm]  ";
                    app.GripperTaskFinalSlider.Limits       = app.kin.qELim*1e3;
                    app.GripperTaskFinalSpinner.Limits      = app.kin.qELim*1e3;
                    app.GripperTaskFinalSlider.Value        = app.qEDes*1e3;
                    app.GripperTaskFinalSpinner.Value       = app.qEDes*1e3;
                elseif app.robot_model == 3
                    app.GripperTaskInitialLabel.Text        = "Gripper [deg]  ";
                    app.GripperTaskInitialSlider.Limits     = rad2deg(app.kin.qELim);
                    app.GripperTaskInitialSpinner.Limits    = rad2deg(app.kin.qELim);
                    app.GripperTaskInitialSlider.Value      = rad2deg(app.kin.qELim(1));
                    app.GripperTaskInitialSpinner.Value     = rad2deg(app.kin.qELim(1));
                    app.GripperTaskFinalLabel.Text          = "Gripper [deg]  ";
                    app.GripperTaskFinalSlider.Limits       = rad2deg(app.kin.qELim);
                    app.GripperTaskFinalSpinner.Limits      = rad2deg(app.kin.qELim);
                    app.GripperTaskFinalSlider.Value        = rad2deg(app.kin.qELim(1));
                    app.GripperTaskFinalSpinner.Value       = rad2deg(app.kin.qELim(1));
                end
            end

        end
        

        function updateEnableState(app)
            
            if app.running_flag == 1
                app.RunButton.Enable = "off";
                app.StopButton.Enable = "on";
                app.SimModeSwitchLabel.Enable = "off";
                app.SimModeSwitch.Enable = "off";
                app.StepsEditFieldLabel.Enable = "off";
                app.StepsEditField.Enable = "off";
                if app.sim_mode == 1
                    app.FinalsEditFieldLabel.Enable = "off";
                    app.FinalsEditField.Enable = "off";
                end
                
                app.Axis1InitialLabel.Enable = 'off';
                app.Axis1InitialSlider.Enable = 'off';
                app.Axis1InitialSpinner.Enable = 'off';
                app.Axis2InitialLabel.Enable = 'off';
                app.Axis2InitialSlider.Enable = 'off';
                app.Axis2InitialSpinner.Enable = 'off';
                app.Axis3InitialLabel.Enable = 'off';
                app.Axis3InitialSlider.Enable = 'off';
                app.Axis3InitialSpinner.Enable = 'off';
                app.Axis4InitialLabel.Enable = 'off';
                app.Axis4InitialSlider.Enable = 'off';
                app.Axis4InitialSpinner.Enable = 'off';
                app.Axis5InitialLabel.Enable = 'off';
                app.Axis5InitialSlider.Enable = 'off';
                app.Axis5InitialSpinner.Enable = 'off';
                app.Axis6InitialLabel.Enable = 'off';
                app.Axis6InitialSlider.Enable = 'off';
                app.Axis6InitialSpinner.Enable = 'off';
                app.Axis7InitialLabel.Enable = 'off';
                app.Axis7InitialSlider.Enable = 'off';
                app.Axis7InitialSpinner.Enable = 'off';
                app.GripperInitialLabel.Enable = 'off';
                app.GripperInitialSlider.Enable = 'off';
                app.GripperInitialSpinner.Enable = 'off';
                    
                if app.trj_profile ~= 0
                    app.Axis1FinalLabel.Enable = 'off';
                    app.Axis1FinalSlider.Enable = 'off';
                    app.Axis1FinalSpinner.Enable = 'off';
                    app.Axis2FinalLabel.Enable = 'off';
                    app.Axis2FinalSlider.Enable = 'off';
                    app.Axis2FinalSpinner.Enable = 'off';
                    app.Axis3FinalLabel.Enable = 'off';
                    app.Axis3FinalSlider.Enable = 'off';
                    app.Axis3FinalSpinner.Enable = 'off';
                    app.Axis4FinalLabel.Enable = 'off';
                    app.Axis4FinalSlider.Enable = 'off';
                    app.Axis4FinalSpinner.Enable = 'off';
                    app.Axis5FinalLabel.Enable = 'off';
                    app.Axis5FinalSlider.Enable = 'off';
                    app.Axis5FinalSpinner.Enable = 'off';
                    app.Axis6FinalLabel.Enable = 'off';
                    app.Axis6FinalSlider.Enable = 'off';
                    app.Axis6FinalSpinner.Enable = 'off';
                    app.Axis7FinalLabel.Enable = 'off';
                    app.Axis7FinalSlider.Enable = 'off';
                    app.Axis7FinalSpinner.Enable = 'off';
                    app.GripperFinalLabel.Enable = 'off';
                    app.GripperFinalSlider.Enable = 'off';
                    app.GripperFinalSpinner.Enable = 'off';
                end
                
                app.InterpolationLabel.Enable = "off";
                app.InterpolationDropDown.Enable = "off";
                app.LimitsLabel.Enable = "off";
                app.VelEditFieldLabel.Enable = 'off';
                app.VelEditField.Enable = 'off';
                app.VelPrcLabel.Enable = "off";
                if app.trj_profile ~= 1
                    app.AccEditFieldLabel.Enable = 'off';
                    app.AccEditField.Enable = 'off';
                    app.AccPrcLabel.Enable = "off";
                end
            else
                app.RunButton.Enable = "on";
                app.StopButton.Enable = "off";
                app.SimModeSwitchLabel.Enable = "on";
                app.SimModeSwitch.Enable = "on";
                app.StepsEditFieldLabel.Enable = "on";
                app.StepsEditField.Enable = "on";
                if app.sim_mode == 1
                    app.FinalsEditFieldLabel.Enable = "on";
                    app.FinalsEditField.Enable = "on";
                end
                
                app.Axis1InitialLabel.Enable = 'on';
                app.Axis1InitialSlider.Enable = 'on';
                app.Axis1InitialSpinner.Enable = 'on';
                app.Axis2InitialLabel.Enable = 'on';
                app.Axis2InitialSlider.Enable = 'on';
                app.Axis2InitialSpinner.Enable = 'on';
                app.Axis3InitialLabel.Enable = 'on';
                app.Axis3InitialSlider.Enable = 'on';
                app.Axis3InitialSpinner.Enable = 'on';
                app.Axis4InitialLabel.Enable = 'on';
                app.Axis4InitialSlider.Enable = 'on';
                app.Axis4InitialSpinner.Enable = 'on';
                app.Axis5InitialLabel.Enable = 'on';
                app.Axis5InitialSlider.Enable = 'on';
                app.Axis5InitialSpinner.Enable = 'on';
                app.Axis6InitialLabel.Enable = 'on';
                app.Axis6InitialSlider.Enable = 'on';
                app.Axis6InitialSpinner.Enable = 'on';
                app.Axis7InitialLabel.Enable = 'on';
                app.Axis7InitialSlider.Enable = 'on';
                app.Axis7InitialSpinner.Enable = 'on';
                app.Axis1FinalLabel.Enable = 'on';
                app.Axis1FinalSlider.Enable = 'on';
                app.Axis1FinalSpinner.Enable = 'on';
                app.Axis2FinalLabel.Enable = 'on';
                app.Axis2FinalSlider.Enable = 'on';
                app.Axis2FinalSpinner.Enable = 'on';
                app.Axis3FinalLabel.Enable = 'on';
                app.Axis3FinalSlider.Enable = 'on';
                app.Axis3FinalSpinner.Enable = 'on';
                app.Axis4FinalLabel.Enable = 'on';
                app.Axis4FinalSlider.Enable = 'on';
                app.Axis4FinalSpinner.Enable = 'on';
                app.Axis5FinalLabel.Enable = 'on';
                app.Axis5FinalSlider.Enable = 'on';
                app.Axis5FinalSpinner.Enable = 'on';
                app.Axis6FinalLabel.Enable = 'on';
                app.Axis6FinalSlider.Enable = 'on';
                app.Axis6FinalSpinner.Enable = 'on';
                app.Axis7FinalLabel.Enable = 'on';
                app.Axis7FinalSlider.Enable = 'on';
                app.Axis7FinalSpinner.Enable = 'on';
                
                if app.ee_att == 1 && (app.robot_model == 1 || app.robot_model == 3)
                    app.GripperInitialLabel.Enable = 'on';
                    app.GripperInitialSlider.Enable = 'on';
                    app.GripperInitialSpinner.Enable = 'on';
                    app.GripperFinalLabel.Enable = 'on';
                    app.GripperFinalSlider.Enable = 'on';
                    app.GripperFinalSpinner.Enable = 'on';
                end
                
                app.InterpolationLabel.Enable = "on";
                app.InterpolationDropDown.Enable = "on";
                app.LimitsLabel.Enable = "on";
                app.VelEditFieldLabel.Enable = "on";
                app.VelEditField.Enable = "on";
                app.VelPrcLabel.Enable = "on";
                if app.trj_profile ~= 1
                    app.AccEditFieldLabel.Enable = "on";
                    app.AccEditField.Enable = "on";
                    app.AccPrcLabel.Enable = "on";
                end
            end
            
            if (app.robot_model == 1 || app.robot_model == 4 || app.robot_model == 5) && app.line_on == 0
                app.HighqualitymodelsSwitch.Enable = "on";
                app.HighqualitymodelsSwitchLabel.Enable = "on";
                app.EnablehighqualitymodelsMenu.Enable = "on";
                app.SlowerLabel.Enable = "on";
            else
                app.HighqualitymodelsSwitch.Enable = "off";
                app.HighqualitymodelsSwitchLabel.Enable = "off";
                app.EnablehighqualitymodelsMenu.Enable = "off";
                app.SlowerLabel.Enable = "off";
            end

            if app.robot_model == 0
                app.HighqualitymodelsSwitch.Visible = "off";
                app.HighqualitymodelsSwitchLabel.Visible = "off";
                app.EnablehighqualitymodelsMenu.Visible = "off";
                app.SlowerLabel.Visible = "off";
            else
                app.HighqualitymodelsSwitch.Visible = "on";
                app.HighqualitymodelsSwitchLabel.Visible = "on";
                app.EnablehighqualitymodelsMenu.Visible = "on";
                app.SlowerLabel.Visible = "on";
            end

        end
        
        function updateSimMode(app)

            app.FinalsEditField.Value = app.tfin_trj;
            if strcmpi(app.SimModeSwitch.Value,'Kinematic') == 1
                app.sim_mode = 0;
                app.tstp = 0.001;
                app.FinalsEditField.Enable = "off";
                app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Quintic'};
            else
                app.sim_mode = 1;
                app.tstp = 0.0001;
                app.FinalsEditField.Enable = "on";
                app.InterpolationDropDown.Items = {'No interpolation', 'Linear', 'Cubic', 'Quintic'};
            end
            
            app.StepsEditField.Value = app.tstp;

        end

        function updateRunButtonState(app)

            if app.tfin_trj < app.tstp
                app.RunButton.Enable = "off";
            else
                app.RunButton.Enable = "on";
            end
            
        end

        function updateFinTimeTrj(app)

            if app.trj_profile ~= 0
                app.tfin_trj = computeTrjTime(app.ini.q_pos,app.fin.q_pos,app.prcnt,app.trj_profile,app.kin.q_velLim,app.kin.q_accLim);
                app.FinalsEditField.Value = app.tfin_trj;
            end
            
        end

        function getFwdGeoIni(app)

            for j = 1:app.kin.n
                app.ini.Ti(:,:,j+1) = app.ini.Ti(:,:,j)*SO3R3_SE3(getRi_j(app.kin.alpha_j(j),app.kin.theta_O_j(j)+app.ini.q_pos(j)),app.kin.ri_j(:,:,j));
            end
            app.ini.Ti(:,:,app.kin.n+2) = app.ini.Ti(:,:,app.kin.n+1)*SO3R3_SE3(getRi_j(app.kin.alpha_j(app.kin.n+1),app.kin.theta_O_j(app.kin.n+1)),app.kin.ri_j(:,:,app.kin.n+1));
            [app.ini.Re,app.ini.t_pos] = SE3_SO3R3(app.ini.Ti(:,:,app.kin.n+2));

        end
        
        function getFwdGeoFin(app)

            for j = 1:app.kin.n
                app.fin.Ti(:,:,j+1) = app.fin.Ti(:,:,j)*SO3R3_SE3(getRi_j(app.kin.alpha_j(j),app.kin.theta_O_j(j)+app.fin.q_pos(j)),app.kin.ri_j(:,:,j));
            end
            app.fin.Ti(:,:,app.kin.n+2) = app.fin.Ti(:,:,app.kin.n+1)*SO3R3_SE3(getRi_j(app.kin.alpha_j(app.kin.n+1),app.kin.theta_O_j(app.kin.n+1)),app.kin.ri_j(:,:,app.kin.n+1));
            [app.fin.Re,app.fin.t_pos] = SE3_SO3R3(app.fin.Ti(:,:,app.kin.n+2));

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % app.RobotModelDropDown.Items = {'Franka Emika Robot', 'Universal Robots UR3', 'Unitree Z1', 'Stäubli RX160', 'Stäubli RX160L'};
            app.RobotModelDropDown.Items = cellstr(app.robot_name);
            app.EditButton.Enable = "off";
            app.DoFSpinner.Limits = [1 7];

            app.EnablehighqualitymodelsMenu.Checked = 'on';
            app.HighqualitymodelsSwitch.Value = 'On';
            app.high_quality = 1;
            app.EnablecoordinateframeMenu.Checked = 'on';
            app.EnableghostmodelsMenu.Checked = 'on';
            app.coord_frame_on = 1;
            app.DmodelsSwitch.Value = 'On';

            % app.DH_custom = zeros(8,4);
            app.DH_custom = [0,0,0.1,0
                             0,-pi/2,0.1,0
                             0,pi/2,0.1,0
                             0.1,pi/2,0.1,0
                             -0.1,-pi/2,0.1,0
                             0,pi/2,0.1,0
                             0,pi/2,0.1,0
                             0,0,0.1,0];
            app.m_j_custom = ones(7,1);
            app.rj_jcj_custom = zeros(3,1,7);
            app.Ij_cj_custom = zeros(3,3,7);
            app.j_type_custom = ones(7,1);
            app.n_custom = 3;

            app.ctr.algo = 0;
            app.ctr.comp_grv = 0;

            app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Quintic'};
            app.InterpolationDropDown.Value = app.trj_profile_text;
            
            app.VelEditField.Value = app.prcnt(1)*100;
            app.AccEditField.Value = app.prcnt(2)*100;

            app.StepsEditField.Value = app.tstp;
            app.FinalsEditField.Value = app.tfin;
            
            updateRobotModel(app)
            updateEndEffectorModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            updateTaskInputValues(app)
            updateSimMode(app)
            updateRunButtonState(app)

            app.TimeLabel.Text = "Not running";
            app.secLabel.Visible = "off";
            app.x1RealtimeLabel.Visible = "off";
            app.q_actq1q2q3q4q5q6q7degLabel.Visible = "off";

            % Plot
            view(app.UIAxes,3)  % Isometric view
            
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            zlabel(app.UIAxes,'Z')
            grid(app.UIAxes,'on')

            set(app.UIAxes,	'Projection','perspective', ...
                            'PlotBoxAspectRatio',[1 1 1], ...
                            'DataAspectRatio',[1 1 1]);
            
            app.UIAxes.Interactions = rotateInteraction;
%             app.UIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.UIAxes.Toolbar = [];
%             zlim(app.UIAxes,[-0.6 inf])

            app.act.q_pos = app.ini.q_pos;
            app.des.q_pos = app.fin.q_pos;
            
            loadModelStruct(app)
            plotAxesOutApp(app)

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Robot Simulation Program\nVersion 0.000007');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Value changed function: RobotModelDropDown
        function RobotModelDropDownValueChanged(app, event)
            value = app.RobotModelDropDown.Value;
            
            rx = 0;
            if (app.robot_model == 4 || app.robot_model == 5)
                rx = 1;
            end

            if strcmp(value, app.robot_name(size(app.robot_name,1)))
                app.robot_model = 0;
            else
                for i = size(app.robot_name,1)-1:-1:1
                    if strcmp(value, app.robot_name(i))
                        app.robot_model = i;
                    end
                end
            end

            if (app.robot_model == 1 || app.robot_model == 2 || app.robot_model == 3) && app.ee_att > 1
                app.exceeding = 1;
            else
                app.exceeding = 0;
            end

            if (app.robot_model == 4 || app.robot_model == 5) && app.ee_att == 1
                if rx == 0
                    app.grip = 1;
                else
                    app.grip = 0;
                end
            end

            updateRobotModel(app)
            updateEndEffectorModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            updateTaskInputValues(app)
            updateEnableState(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changed function: EndEffectorDropDown
        function EndEffectorDropDownValueChanged(app, event)
            value = app.EndEffectorDropDown.Value;
            
            switch value
                case 'Gripper'
                    app.ee_att = 1;
                case 'F/T Sensor'
                    app.ee_att = 1;
                case 'F/T Sensor + Gripper'
                    app.ee_att = 2;
                case 'F/T Sensor + Ball&Spring'
                    app.ee_att = 3;
                otherwise
                    app.ee_att = 0;
            end

            updateRobotModel(app)
            updateEndEffectorModel(app)
            updateSliderSpinnerValues(app)
            updateTaskInputValues(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis1InitialSlider
        function Axis1InitialSliderValueChanging(app, event)
            if app.kin.j_type(1)
                app.ini.q_pos(1) = deg2rad(event.Value);
            else
                app.ini.q_pos(1) = 1e-3*event.Value;
            end
            app.Axis1InitialSpinner.Value = event.Value;

            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis1InitialSpinner
        function Axis1InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(1)
                app.ini.q_pos(1) = deg2rad(event.Value);
            else
                app.ini.q_pos(1) = 1e-3*event.Value;
            end
            app.Axis1InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2InitialSlider
        function Axis2InitialSliderValueChanging(app, event)
            if app.kin.j_type(2)
                app.ini.q_pos(2) = deg2rad(event.Value);
            else
                app.ini.q_pos(2) = 1e-3*event.Value;
            end
            app.Axis2InitialSpinner.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2InitialSpinner
        function Axis2InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(2)
                app.ini.q_pos(2) = deg2rad(event.Value);
            else
                app.ini.q_pos(2) = 1e-3*event.Value;
            end
            app.Axis2InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3InitialSlider
        function Axis3InitialSliderValueChanging(app, event)
            if app.kin.j_type(3)
                app.ini.q_pos(3) = deg2rad(event.Value);
            else
                app.ini.q_pos(3) = 1e-3*event.Value;
            end
            app.Axis3InitialSpinner.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3InitialSpinner
        function Axis3InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(3)
                app.ini.q_pos(3) = deg2rad(event.Value);
            else
                app.ini.q_pos(3) = 1e-3*event.Value;
            end
            app.Axis3InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4InitialSlider
        function Axis4InitialSliderValueChanging(app, event)
            if app.kin.j_type(4)
                app.ini.q_pos(4) = deg2rad(event.Value);
            else
                app.ini.q_pos(4) = 1e-3*event.Value;
            end
            app.Axis4InitialSpinner.Value = event.Value;

            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4InitialSpinner
        function Axis4InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(4)
                app.ini.q_pos(4) = deg2rad(event.Value);
            else
                app.ini.q_pos(4) = 1e-3*event.Value;
            end
            app.Axis4InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5InitialSlider
        function Axis5InitialSliderValueChanging(app, event)
            if app.kin.j_type(5)
                app.ini.q_pos(5) = deg2rad(event.Value);
            else
                app.ini.q_pos(5) = 1e-3*event.Value;
            end
            app.Axis5InitialSpinner.Value = event.Value;

            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5InitialSpinner
        function Axis5InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(5)
                app.ini.q_pos(5) = deg2rad(event.Value);
            else
                app.ini.q_pos(5) = 1e-3*event.Value;
            end
            app.Axis5InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6InitialSlider
        function Axis6InitialSliderValueChanging(app, event)
            if app.kin.j_type(6)
                app.ini.q_pos(6) = deg2rad(event.Value);
            else
                app.ini.q_pos(6) = 1e-3*event.Value;
            end
            app.Axis6InitialSpinner.Value = event.Value;

            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6InitialSpinner
        function Axis6InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(6)
                app.ini.q_pos(6) = deg2rad(event.Value);
            else
                app.ini.q_pos(6) = 1e-3*event.Value;
            end
            app.Axis6InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7InitialSlider
        function Axis7InitialSliderValueChanging(app, event)
            if app.kin.j_type(7)
                app.ini.q_pos(7) = deg2rad(event.Value);
            else
                app.ini.q_pos(7) = 1e-3*event.Value;
            end
            app.Axis7InitialSpinner.Value = event.Value;

            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7InitialSpinner
        function Axis7InitialSpinnerValueChanging(app, event)
            if app.kin.j_type(7)
                app.ini.q_pos(7) = deg2rad(event.Value);
            else
                app.ini.q_pos(7) = 1e-3*event.Value;
            end
            app.Axis7InitialSlider.Value = event.Value;
            
            getFwdGeoIni(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: GripperInitialSlider
        function GripperInitialSliderValueChanging(app, event)
            if app.robot_model == 3
                app.qEIni = deg2rad(event.Value);
            else
                app.qEIni = event.Value*1e-3;
            end
            app.GripperInitialSpinner.Value = event.Value;
            app.GripperTaskInitialSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: GripperInitialSpinner
        function GripperInitialSpinnerValueChanging(app, event)
            if app.robot_model == 3
                app.qEIni = deg2rad(event.Value);
            else
                app.qEIni = event.Value*1e-3;
            end
            app.GripperInitialSlider.Value = event.Value;
            app.GripperTaskInitialSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: Axis1FinalSlider
        function Axis1FinalSliderValueChanging(app, event)
            if app.kin.j_type(1)
                app.fin.q_pos(1) = deg2rad(event.Value);
            else
                app.fin.q_pos(1) = 1e-3*event.Value;
            end
            app.Axis1FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis1FinalSpinner
        function Axis1FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(1)
                app.fin.q_pos(1) = deg2rad(event.Value);
            else
                app.fin.q_pos(1) = 1e-3*event.Value;
            end
            app.Axis1FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2FinalSlider
        function Axis2FinalSliderValueChanging(app, event)
            if app.kin.j_type(2)
                app.fin.q_pos(2) = deg2rad(event.Value);
            else
                app.fin.q_pos(2) = 1e-3*event.Value;
            end
            app.Axis2FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2FinalSpinner
        function Axis2FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(2)
                app.fin.q_pos(2) = deg2rad(event.Value);
            else
                app.fin.q_pos(2) = 1e-3*event.Value;
            end
            app.Axis2FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3FinalSlider
        function Axis3FinalSliderValueChanging(app, event)
            if app.kin.j_type(3)
                app.fin.q_pos(3) = deg2rad(event.Value);
            else
                app.fin.q_pos(3) = 1e-3*event.Value;
            end
            app.Axis3FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3FinalSpinner
        function Axis3FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(3)
                app.fin.q_pos(3) = deg2rad(event.Value);
            else
                app.fin.q_pos(3) = 1e-3*event.Value;
            end
            app.Axis3FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4FinalSlider
        function Axis4FinalSliderValueChanging(app, event)
            if app.kin.j_type(4)
                app.fin.q_pos(4) = deg2rad(event.Value);
            else
                app.fin.q_pos(4) = 1e-3*event.Value;
            end
            app.Axis4FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4FinalSpinner
        function Axis4FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(4)
                app.fin.q_pos(4) = deg2rad(event.Value);
            else
                app.fin.q_pos(4) = 1e-3*event.Value;
            end
            app.Axis4FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5FinalSlider
        function Axis5FinalSliderValueChanging(app, event)
            if app.kin.j_type(5)
                app.fin.q_pos(5) = deg2rad(event.Value);
            else
                app.fin.q_pos(5) = 1e-3*event.Value;
            end
            app.Axis5FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5FinalSpinner
        function Axis5FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(5)
                app.fin.q_pos(5) = deg2rad(event.Value);
            else
                app.fin.q_pos(5) = 1e-3*event.Value;
            end
            app.Axis5FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6FinalSlider
        function Axis6FinalSliderValueChanging(app, event)
            if app.kin.j_type(6)
                app.fin.q_pos(6) = deg2rad(event.Value);
            else
                app.fin.q_pos(6) = 1e-3*event.Value;
            end
            app.Axis6FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6FinalSpinner
        function Axis6FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(6)
                app.fin.q_pos(6) = deg2rad(event.Value);
            else
                app.fin.q_pos(6) = 1e-3*event.Value;
            end
            app.Axis6FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7FinalSlider
        function Axis7FinalSliderValueChanging(app, event)
            if app.kin.j_type(7)
                app.fin.q_pos(7) = deg2rad(event.Value);
            else
                app.fin.q_pos(7) = 1e-3*event.Value;
            end
            app.Axis7FinalSpinner.Value = event.Value;

            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7FinalSpinner
        function Axis7FinalSpinnerValueChanging(app, event)
            if app.kin.j_type(7)
                app.fin.q_pos(7) = deg2rad(event.Value);
            else
                app.fin.q_pos(7) = 1e-3*event.Value;
            end
            app.Axis7FinalSlider.Value = event.Value;
            
            getFwdGeoFin(app)
            updateFinTimeTrj(app)
            updateRunButtonState(app)
            plotAxesOutApp(app)
        end

        % Value changing function: GripperFinalSlider
        function GripperFinalSliderValueChanging(app, event)
            if app.robot_model == 3
                app.qEDes = deg2rad(event.Value);
            else
                app.qEDes = event.Value*1e-3;
            end
            app.GripperFinalSpinner.Value = event.Value;
            app.GripperTaskFinalSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: GripperFinalSpinner
        function GripperFinalSpinnerValueChanging(app, event)
            if app.robot_model == 3
                app.qEDes = deg2rad(event.Value);
            else
                app.qEDes = event.Value*1e-3;
            end
            app.GripperFinalSlider.Value = event.Value;
            app.GripperTaskFinalSlider.Value = event.Value;
            
            plotAxesOutApp(app)
        end

        % Value changed function: HighqualitymodelsSwitch
        function HighqualitymodelsSwitchValueChanged(app, event)
            value = app.HighqualitymodelsSwitch.Value;

            if strcmpi(value,'On') == 1
                app.high_quality = 1;
                app.EnablehighqualitymodelsMenu.Checked = 'on';
            else
                app.high_quality = 0;
                app.EnablehighqualitymodelsMenu.Checked = 'off';
            end
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablehighqualitymodelsMenu
        function EnablehighqualitymodelsMenuSelected(app, event)
            if app.high_quality == 1
                app.EnablehighqualitymodelsMenu.Checked = 'off';
                app.HighqualitymodelsSwitch.Value = 'Off';
                app.high_quality = 0;
            else
                app.EnablehighqualitymodelsMenu.Checked = 'on';
                app.HighqualitymodelsSwitch.Value = 'On';
                app.high_quality = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablecoordinateframeMenu
        function EnablecoordinateframeMenuSelected(app, event)
            if app.coord_frame_on == 1
                app.EnablecoordinateframeMenu.Checked = 'off';
                app.coord_frame_on = 0;
            else
                app.EnablecoordinateframeMenu.Checked = 'on';
                app.coord_frame_on = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            app.trj_profile_text = app.InterpolationDropDown.Value;

            switch app.trj_profile_text
                case 'Linear'
                    app.trj_profile = 1;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.VelPrcLabel.Visible = 'on';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.AccPrcLabel.Visible = 'off';
                case 'Cubic'
                    app.trj_profile = 2;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.VelPrcLabel.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.AccPrcLabel.Visible = 'on';
                case 'Quintic'
                    app.trj_profile = 3;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.VelPrcLabel.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.AccPrcLabel.Visible = 'on';
                otherwise
                    app.trj_profile = 0;
                    app.LimitsLabel.Visible = 'off';
                    app.VelEditField.Visible = 'off';
                    app.VelEditFieldLabel.Visible = 'off';
                    app.VelPrcLabel.Visible = 'off';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.AccPrcLabel.Visible = 'off';
            end
            
            updateFinTimeTrj(app)
            app.FinalsEditField.Value = app.tfin_trj;
        end

        % Value changed function: VelEditField
        function VelEditFieldValueChanged(app, event)
            app.prcnt(1) = app.VelEditField.Value/100;

            app.tfin_trj = computeTrjTime(app.ini.q_pos,app.fin.q_pos,app.prcnt,app.trj_profile,app.kin.q_velLim,app.kin.q_accLim);
            app.FinalsEditField.Value = app.tfin_trj;
        end

        % Value changed function: AccEditField
        function AccEditFieldValueChanged(app, event)
            app.prcnt(2) = app.AccEditField.Value/100;

            app.tfin_trj = computeTrjTime(app.ini.q_pos,app.fin.q_pos,app.prcnt,app.trj_profile,app.kin.q_velLim,app.kin.q_accLim);
            app.FinalsEditField.Value = app.tfin_trj;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.running_flag = 1;
            
            updateEnableState(app)
            app.TimeLabel.Text = "Time:";
            app.secLabel.Visible = "on";
            app.x1RealtimeLabel.Visible = "on";
            app.q_actq1q2q3q4q5q6q7degLabel.Visible = "on";

            if app.sim_mode == 1
                app.tfin = app.FinalsEditField.Value;
                app.x1RealtimeLabel.Text = append('(x', num2str(app.time_const,'%.1f'), ' Real-time)');
                run_dynamic(app)
            else
                app.x1RealtimeLabel.Text = '(x1 Real-time)';
                run_kinematic(app)
            end

            app.running_flag = 0;

            updateEnableState(app)
            app.TimeLabel.Text = "Stopped";
            app.secLabel.Visible = "off";
            app.x1RealtimeLabel.Visible = "off";
            app.q_actq1q2q3q4q5q6q7degLabel.Visible = "off";
            getFwdGeoIni(app) % Update fwd geo ini to the position before run again
            getFwdGeoFin(app) % Update fwd geo fin to the position before run again
            plotAxesOutApp(app)

        end

        % Menu selected function: EnableghostmodelsMenu
        function EnableghostmodelsMenuSelected(app, event)
            if app.ghost_on == 1
                app.EnableghostmodelsMenu.Checked = 'off';
                app.ghost_on = 0;
            else
                app.EnableghostmodelsMenu.Checked = 'on';
                app.ghost_on = 1;
            end
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changed function: SimModeSwitch
        function SimModeSwitchValueChanged(app, event)
            updateSimMode(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.running_flag = 0;
            
            updateEnableState(app);
            plotAxesOutApp(app)
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            app.tstp = app.StepsEditField.Value;
        end

        % Value changed function: FinalsEditField
        function FinalsEditFieldValueChanged(app, event)
            app.tfin = app.FinalsEditField.Value;
            
            if app.tfin < app.tstp
                app.RunButton.Enable = "off";
            else
                app.RunButton.Enable = "on";
            end
        end

        % Cell edit callback: GainTable
        function GainTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2) == 1
                app.ctr.Kp_jnt_pid(indices(1)) = newData;
            elseif  indices(2) == 2
                app.ctr.Ki_jnt_pid(indices(1)) = newData;
            else
                app.ctr.Kd_jnt_pid(indices(1)) = newData;
            end
        end

        % Value changed function: CycleEditField
        function CycleEditFieldValueChanged(app, event)
            app.ctr.tcyc = app.CycleEditField.Value;
        end

        % Value changed function: CompGravityCheckBox
        function CompGravityCheckBoxValueChanged(app, event)
            app.ctr.comp_grv = app.CompGravityCheckBox.Value;
        end

        % Value changed function: DmodelsSwitch
        function DmodelsSwitchValueChanged(app, event)
            value = app.DmodelsSwitch.Value;

            if strcmpi(value,'On') == 1
                app.line_on = 0;
                app.HighqualitymodelsSwitch.Enable = "on";
            else
                app.line_on = 1;
                app.HighqualitymodelsSwitch.Enable = "off";
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
            if app.EditButton.Value
                app.DHTable.ColumnEditable = logical([0 1 1 1 1]);
                app.DoFSpinner.Enable = "on";
                app.q_1ButtonGroup.Enable = "on";
                app.q_2ButtonGroup.Enable = "on";
                app.q_3ButtonGroup.Enable = "on";
                app.q_4ButtonGroup.Enable = "on";
                app.q_5ButtonGroup.Enable = "on";
                app.q_6ButtonGroup.Enable = "on";
                app.q_7ButtonGroup.Enable = "on";
            else
                app.DHTable.ColumnEditable = logical([0 0 0 0 0]);
                app.DoFSpinner.Enable = "off";
                app.q_1ButtonGroup.Enable = "off";
                app.q_2ButtonGroup.Enable = "off";
                app.q_3ButtonGroup.Enable = "off";
                app.q_4ButtonGroup.Enable = "off";
                app.q_5ButtonGroup.Enable = "off";
                app.q_6ButtonGroup.Enable = "off";
                app.q_7ButtonGroup.Enable = "off";
            end
        end

        % Value changed function: DoFSpinner
        function DoFSpinnerValueChanged(app, event)
            app.n_custom = app.DoFSpinner.Value;
            
            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            updateTaskInputValues(app)
            plotAxesOutApp(app)
        end

        % Cell edit callback: DHTable
        function DHTableCellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);

            if indices(2) == 2
                app.DH_custom(indices(1),1) = newData*1e-3;
            elseif indices(2) == 3
                app.DH_custom(indices(1),2) = deg2rad(newData);
            elseif indices(2) == 4
                app.DH_custom(indices(1),3) = newData*1e-3;
            else
                app.DH_custom(indices(1),4) = deg2rad(newData);
            end

            updateRobotModel(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_1ButtonGroup
        function q_1ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(1,:) = app.Button.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_2ButtonGroup
        function q_2ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(2,:) = app.Button_2.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_3ButtonGroup
        function q_3ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(3,:) = app.Button_3.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_4ButtonGroup
        function q_4ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(4,:) = app.Button_4.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_5ButtonGroup
        function q_5ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(5,:) = app.Button_5.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_6ButtonGroup
        function q_6ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(6,:) = app.Button_6.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: q_7ButtonGroup
        function q_7ButtonGroupSelectionChanged(app, event)
            app.j_type_custom(7,:) = app.Button_7.Value;

            updateRobotModel(app)
            resetJointValues(app)
            updateSliderSpinnerValues(app)
            plotAxesOutApp(app)
        end

        % Value changed function: ControlTypeDropDown
        function ControlTypeDropDownValueChanged(app, event)
            if strcmp(app.ControlTypeDropDown.Value, 'IDC')
                app.ctr.algo = 1;
                app.CompGravityCheckBox.Enable = "off";
            else
                app.ctr.algo = 0;
                app.CompGravityCheckBox.Enable = "on";
            end
            updateControlParams(app)
        end

        % Button down function: InitialJointTab
        function InitialJointTabButtonDown(app, event)
            app.task_mode = 0;

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Button down function: FinalJointTab
        function FinalJointTabButtonDown(app, event)
            app.task_mode = 0;
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Button down function: InitialTaskTab
        function InitialTaskTabButtonDown(app, event)
            app.task_mode = 1;
            
            % getFwdGeoIni(app)
            app.ini.r_pos = getEulerPosVec(app.ini.Re,app.eulerSet);

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Button down function: FinalTaskTab
        function FinalTaskTabButtonDown(app, event)
            app.task_mode = 2;
            
            % getFwdGeoFin(app)
            app.fin.r_pos = getEulerPosVec(app.fin.Re,app.eulerSet);
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: XAxisInitialSlider
        function XAxisInitialSliderValueChanging(app, event)
            app.ini.t_pos(1) = 1e-3*event.Value;
            app.XAxisInitialSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: XAxisInitialSpinner
        function XAxisInitialSpinnerValueChanging(app, event)
            app.ini.t_pos(1) = 1e-3*event.Value;
            app.XAxisInitialSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: YAxisInitialSlider
        function YAxisInitialSliderValueChanging(app, event)
            app.ini.t_pos(2) = 1e-3*event.Value;
            app.YAxisInitialSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: YAxisInitialSpinner
        function YAxisInitialSpinnerValueChanging(app, event)
            app.ini.t_pos(2) = 1e-3*event.Value;
            app.YAxisInitialSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: ZAxisInitialSlider
        function ZAxisInitialSliderValueChanging(app, event)
            app.ini.t_pos(3) = 1e-3*event.Value;
            app.ZAxisInitialSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: ZAxisInitialSpinner
        function ZAxisInitialSpinnerValueChanging(app, event)
            app.ini.t_pos(3) = 1e-3*event.Value;
            app.ZAxisInitialSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: PhiInitialSlider
        function PhiInitialSliderValueChanging(app, event)
            app.ini.r_pos(1) = deg2rad(event.Value);
            app.PhiInitialSpinner.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PhiInitialSpinner
        function PhiInitialSpinnerValueChanging(app, event)
            app.ini.r_pos(1) = deg2rad(event.Value);
            app.PhiInitialSlider.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: ThetaInitialSlider
        function ThetaInitialSliderValueChanging(app, event)
            app.ini.r_pos(2) = deg2rad(event.Value);
            app.ThetaInitialSpinner.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: ThetaInitialSpinner
        function ThetaInitialSpinnerValueChanging(app, event)
            app.ini.r_pos(2) = deg2rad(event.Value);
            app.ThetaInitialSlider.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PsiInitialSlider
        function PsiInitialSliderValueChanging(app, event)
            app.ini.r_pos(3) = deg2rad(event.Value);
            app.PsiInitialSpinner.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PsiInitialSpinner
        function PsiInitialSpinnerValueChanging(app, event)
            app.ini.r_pos(3) = deg2rad(event.Value);
            app.PsiInitialSlider.Value = event.Value;

            app.ini.Re = getRotMatfromEA(app.ini.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: GripperTaskInitialSlider
        function GripperTaskInitialSliderValueChanging(app, event)
            if app.robot_model == 3
                app.qEIni = deg2rad(event.Value);
            else
                app.qEIni = event.Value*1e-3;
            end
            app.GripperInitialSpinner.Value = event.Value;
            app.GripperTaskInitialSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: GripperTaskInitialSpinner
        function GripperTaskInitialSpinnerValueChanging(app, event)
            if app.robot_model == 3
                app.qEIni = deg2rad(event.Value);
            else
                app.qEIni = event.Value*1e-3;
            end
            app.GripperInitialSlider.Value = event.Value;
            app.GripperTaskInitialSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: XAxisFinalSlider
        function XAxisFinalSliderValueChanging(app, event)
            app.fin.t_pos(1) = 1e-3*event.Value;
            app.XAxisFinalSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: XAxisFinalSpinner
        function XAxisFinalSpinnerValueChanging(app, event)
            app.fin.t_pos(1) = 1e-3*event.Value;
            app.XAxisFinalSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: YAxisFinalSlider
        function YAxisFinalSliderValueChanging(app, event)
            app.fin.t_pos(2) = 1e-3*event.Value;
            app.YAxisFinalSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: YAxisFinalSpinner
        function YAxisFinalSpinnerValueChanging(app, event)
            app.fin.t_pos(2) = 1e-3*event.Value;
            app.YAxisFinalSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: ZAxisFinalSlider
        function ZAxisFinalSliderValueChanging(app, event)
            app.fin.t_pos(3) = 1e-3*event.Value;
            app.ZAxisFinalSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: ZAxisFinalSpinner
        function ZAxisFinalSpinnerValueChanging(app, event)
            app.fin.t_pos(3) = 1e-3*event.Value;
            app.ZAxisFinalSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: PhiFinalSlider
        function PhiFinalSliderValueChanging(app, event)
            app.fin.r_pos(1) = deg2rad(event.Value);
            app.PhiFinalSpinner.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PhiFinalSpinner
        function PhiFinalSpinnerValueChanging(app, event)
            app.fin.r_pos(1) = deg2rad(event.Value);
            app.PhiFinalSlider.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: ThetaFinalSlider
        function ThetaFinalSliderValueChanging(app, event)
            app.fin.r_pos(2) = deg2rad(event.Value);
            app.ThetaFinalSpinner.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: ThetaFinalSpinner
        function ThetaFinalSpinnerValueChanging(app, event)
            app.fin.r_pos(2) = deg2rad(event.Value);
            app.ThetaFinalSlider.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PsiFinalSlider
        function PsiFinalSliderValueChanging(app, event)
            app.fin.r_pos(3) = deg2rad(event.Value);
            app.PsiFinalSpinner.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: PsiFinalSpinner
        function PsiFinalSpinnerValueChanging(app, event)
            app.fin.r_pos(3) = deg2rad(event.Value);
            app.PsiFinalSlider.Value = event.Value;

            app.fin.Re = getRotMatfromEA(app.fin.r_pos, app.eulerSet);
            plotAxesOutApp(app)
        end

        % Value changing function: GripperTaskFinalSlider
        function GripperTaskFinalSliderValueChanging(app, event)
            if app.robot_model == 3
                app.qEDes = deg2rad(event.Value);
            else
                app.qEDes = event.Value*1e-3;
            end
            app.GripperFinalSpinner.Value = event.Value;
            app.GripperTaskFinalSpinner.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Value changing function: GripperTaskFinalSpinner
        function GripperTaskFinalSpinnerValueChanging(app, event)
            if app.robot_model == 3
                app.qEFin = deg2rad(event.Value);
            else
                app.qEFin = event.Value*1e-3;
            end
            app.GripperFinalSlider.Value = event.Value;
            app.GripperTaskFinalSlider.Value = event.Value;

            plotAxesOutApp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {549, 549, 549};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {549, 549};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 342};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.PlotOptionsMenu = uimenu(app.ViewMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.EnablehighqualitymodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnablehighqualitymodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablehighqualitymodelsMenuSelected, true);
            app.EnablehighqualitymodelsMenu.Text = 'Enable high quality models';
            app.EnablecoordinateframeMenu = uimenu(app.PlotOptionsMenu);
            app.EnablecoordinateframeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablecoordinateframeMenuSelected, true);
            app.EnablecoordinateframeMenu.Text = 'Enable coordinate frame';
            app.EnableghostmodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnableghostmodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableghostmodelsMenuSelected, true);
            app.EnableghostmodelsMenu.Text = 'Enable ghost models';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.WindowMenu = uimenu(app.UIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftTabGroup = uitabgroup(app.LeftPanel);
            app.KinematicsTab = uitab(app.LeftTabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.DHTableLabel = uilabel(app.KinematicsTab);
            app.DHTableLabel.Text = 'DH Table:';
            app.DHTable = uitable(app.KinematicsTab);
            app.DHTable.ColumnName = {'a_j'; 'alpha_j'; 'd_j'; 'theta_j'};
            app.DHTable.RowName = {};
            app.DHTable.CellEditCallback = createCallbackFcn(app, @DHTableCellEdit, true);
            app.mmdegmmdegLabel = uilabel(app.KinematicsTab);
            app.mmdegmmdegLabel.Text = '[mm]         [deg]         [mm]         [deg]';
            app.Panel = uipanel(app.KinematicsTab);
            app.q_jPanel = uipanel(app.Panel);
            app.q_jRPLabel = uilabel(app.q_jPanel);
            app.q_jRPLabel.Text = 'q_j   R   P';
            app.q_1ButtonGroup = uibuttongroup(app.Panel);
            app.q_1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_1ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.q_1ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.q_1ButtonGroup);
            app.Button2.Text = 'Button2';
            app.q_1Label = uilabel(app.q_1ButtonGroup);
            app.q_1Label.Text = 'q_1';
            app.q_2ButtonGroup = uibuttongroup(app.Panel);
            app.q_2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_2ButtonGroupSelectionChanged, true);
            app.Button_2 = uiradiobutton(app.q_2ButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2_2 = uiradiobutton(app.q_2ButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.q_2Label = uilabel(app.q_2ButtonGroup);
            app.q_2Label.Text = 'q_2';
            app.q_3ButtonGroup = uibuttongroup(app.Panel);
            app.q_3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_3ButtonGroupSelectionChanged, true);
            app.Button_3 = uiradiobutton(app.q_3ButtonGroup);
            app.Button_3.Text = 'Button';
            app.Button_3.Value = true;
            app.Button2_3 = uiradiobutton(app.q_3ButtonGroup);
            app.Button2_3.Text = 'Button2';
            app.q_3Label = uilabel(app.q_3ButtonGroup);
            app.q_3Label.Text = 'q_3';
            app.q_4ButtonGroup = uibuttongroup(app.Panel);
            app.q_4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_4ButtonGroupSelectionChanged, true);
            app.Button_4 = uiradiobutton(app.q_4ButtonGroup);
            app.Button_4.Text = 'Button';
            app.Button_4.Value = true;
            app.Button2_4 = uiradiobutton(app.q_4ButtonGroup);
            app.Button2_4.Text = 'Button2';
            app.q_4Label = uilabel(app.q_4ButtonGroup);
            app.q_4Label.Text = 'q_4';
            app.q_5ButtonGroup = uibuttongroup(app.Panel);
            app.q_5ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_5ButtonGroupSelectionChanged, true);
            app.Button_5 = uiradiobutton(app.q_5ButtonGroup);
            app.Button_5.Text = 'Button';
            app.Button_5.Value = true;
            app.Button2_5 = uiradiobutton(app.q_5ButtonGroup);
            app.Button2_5.Text = 'Button2';
            app.q_5Label = uilabel(app.q_5ButtonGroup);
            app.q_5Label.Text = 'q_5';
            app.q_6ButtonGroup = uibuttongroup(app.Panel);
            app.q_6ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_6ButtonGroupSelectionChanged, true);
            app.Button_6 = uiradiobutton(app.q_6ButtonGroup);
            app.Button_6.Text = 'Button';
            app.Button_6.Value = true;
            app.Button2_6 = uiradiobutton(app.q_6ButtonGroup);
            app.Button2_6.Text = 'Button2';
            app.q_6Label = uilabel(app.q_6ButtonGroup);
            app.q_6Label.Text = 'q_6';
            app.q_7ButtonGroup = uibuttongroup(app.Panel);
            app.q_7ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @q_7ButtonGroupSelectionChanged, true);
            app.Button_7 = uiradiobutton(app.q_7ButtonGroup);
            app.Button_7.Text = 'Button';
            app.Button_7.Value = true;
            app.Button2_7 = uiradiobutton(app.q_7ButtonGroup);
            app.Button2_7.Text = 'Button2';
            app.q_7Label = uilabel(app.q_7ButtonGroup);
            app.q_7Label.Text = 'q_7';
            app.jLabel = uilabel(app.KinematicsTab);
            app.jLabel.Text = 'j';
            app.LimitsTab = uitab(app.LeftTabGroup);
            app.LimitsTab.Title = 'Limits';
            app.JointPosMaxTable = uitable(app.LimitsTab);
            app.JointPosMaxTable.ColumnName = '';
            app.JointPosMaxTable.RowName = {};
            app.JointPosMaxdegLabel = uilabel(app.LimitsTab);
            app.JointPosMaxdegLabel.Text = 'Joint Pos Max [deg]:';
            app.JointPosMinTable = uitable(app.LimitsTab);
            app.JointPosMinTable.ColumnName = '';
            app.JointPosMinTable.RowName = {};
            app.JointPosMindegLabel = uilabel(app.LimitsTab);
            app.JointPosMindegLabel.Text = 'Joint Pos Min [deg]:';
            app.JointVelMagTable = uitable(app.LimitsTab);
            app.JointVelMagTable.ColumnName = '';
            app.JointVelMagTable.RowName = {};
            app.JointVelMagdegsLabel = uilabel(app.LimitsTab);
            app.JointVelMagdegsLabel.Text = 'Joint Vel Mag [deg/s]:';
            app.JointAccMagTable = uitable(app.LimitsTab);
            app.JointAccMagTable.ColumnName = '';
            app.JointAccMagTable.RowName = {};
            app.JointAccMagdegsLabel = uilabel(app.LimitsTab);
            app.JointAccMagdegsLabel.Text = 'Joint Acc Mag [deg/s^2]:';
            app.DynamicsTab = uitab(app.LeftTabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.AdditionalmodelsLabel = uilabel(app.DynamicsTab);
            app.AdditionalmodelsLabel.Text = 'Additional models:';
            app.SpringCheckBox = uicheckbox(app.DynamicsTab);
            app.SpringCheckBox.Text = 'Spring';
            app.SpringCheckBox.Value = true;
            app.FrictionCheckBox = uicheckbox(app.DynamicsTab);
            app.FrictionCheckBox.Text = 'Friction';
            app.FrictionCheckBox.Value = true;
            app.InertialParametersPanel = uipanel(app.DynamicsTab);
            app.InertialParametersPanel.Title = 'Inertial Parameters';
            app.MassTable = uitable(app.InertialParametersPanel);
            app.MassTable.ColumnName = '';
            app.MassTable.RowName = {};
            app.MasskgLabel = uilabel(app.InertialParametersPanel);
            app.MasskgLabel.Text = 'Mass [kg]:';
            app.CenterofMassTable = uitable(app.InertialParametersPanel);
            app.CenterofMassTable.ColumnName = '';
            app.CenterofMassTable.RowName = {};
            app.CenterofMassmLabel = uilabel(app.InertialParametersPanel);
            app.CenterofMassmLabel.Text = 'Center of Mass [m]:';
            app.UniqueElementsVectorofInertiaTensorsmLabel = uilabel(app.InertialParametersPanel);
            app.UniqueElementsVectorofInertiaTensorsmLabel.Text = 'Unique Elements Vector of Inertia Tensors [m]:';
            app.CenterofMassTable_2 = uitable(app.InertialParametersPanel);
            app.CenterofMassTable_2.ColumnName = '';
            app.CenterofMassTable_2.RowName = {};
            app.ControlTab = uitab(app.LeftTabGroup);
            app.ControlTab.Title = 'Control';
            app.ControlGainsLabel = uilabel(app.ControlTab);
            app.ControlGainsLabel.Text = 'Control Gains';
            app.GainTable = uitable(app.ControlTab);
            app.GainTable.ColumnName = {'Kp'; 'Ki'; 'Kd'};
            app.GainTable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.GainTable.CellEditCallback = createCallbackFcn(app, @GainTableCellEdit, true);
            app.CompSpringCheckBox = uicheckbox(app.ControlTab);
            app.CompSpringCheckBox.Enable = 'off';
            app.CompSpringCheckBox.Text = 'Spring';
            app.CompFrictionCheckBox = uicheckbox(app.ControlTab);
            app.CompFrictionCheckBox.Enable = 'off';
            app.CompFrictionCheckBox.Text = 'Friction';
            app.CompGravityCheckBox = uicheckbox(app.ControlTab);
            app.CompGravityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompGravityCheckBoxValueChanged, true);
            app.CompGravityCheckBox.Text = 'Gravity';
            app.secLabel_2 = uilabel(app.ControlTab);
            app.secLabel_2.Enable = 'off';
            app.secLabel_2.Text = 'sec';
            app.CycleEditFieldLabel = uilabel(app.ControlTab);
            app.CycleEditFieldLabel.Text = 'Cycle';
            app.CycleEditField = uieditfield(app.ControlTab, 'numeric');
            app.CycleEditField.Limits = [1e-05 1];
            app.CycleEditField.ValueChangedFcn = createCallbackFcn(app, @CycleEditFieldValueChanged, true);
            app.CycleEditField.Value = 0.004;
            app.ControlTypeDropDownLabel = uilabel(app.ControlTab);
            app.ControlTypeDropDownLabel.Text = 'Control Type';
            app.ControlTypeDropDown = uidropdown(app.ControlTab);
            app.ControlTypeDropDown.Items = {'PID', 'IDC'};
            app.ControlTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlTypeDropDownValueChanged, true);
            app.ControlTypeDropDown.Value = 'PID';
            app.SimulationPanel = uipanel(app.LeftPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.RunButton = uibutton(app.SimulationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SimModeSwitchLabel = uilabel(app.SimulationPanel);
            app.SimModeSwitchLabel.Text = 'Sim Mode:';
            app.SimModeSwitch = uiswitch(app.SimulationPanel, 'slider');
            app.SimModeSwitch.Items = {'Kinematic', 'Dynamic'};
            app.SimModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SimModeSwitchValueChanged, true);
            app.SimModeSwitch.Value = 'Kinematic';
            app.StopButton = uibutton(app.SimulationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.FinalsEditFieldLabel = uilabel(app.SimulationPanel);
            app.FinalsEditFieldLabel.Text = 'Final [s]';
            app.FinalsEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.FinalsEditField.Limits = [0 600];
            app.FinalsEditField.ValueChangedFcn = createCallbackFcn(app, @FinalsEditFieldValueChanged, true);
            app.FinalsEditField.Value = 1;
            app.StepsEditFieldLabel = uilabel(app.SimulationPanel);
            app.StepsEditFieldLabel.Text = 'Step [s]';
            app.StepsEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.StepsEditField.Limits = [1e-05 0.001];
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Value = 0.001;
            app.RobotModelDropDownLabel = uilabel(app.LeftPanel);
            app.RobotModelDropDownLabel.Text = 'Robot Model';
            app.RobotModelDropDown = uidropdown(app.LeftPanel);
            app.RobotModelDropDown.Items = {'Franka Emika Robot', 'Universal Robots UR3', 'Unitree Z1', 'Stäubli RX160', 'Stäubli RX160L'};
            app.RobotModelDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotModelDropDownValueChanged, true);
            app.RobotModelDropDown.Value = 'Franka Emika Robot';
            app.EndEffectorDropDownLabel = uilabel(app.LeftPanel);
            app.EndEffectorDropDownLabel.Text = 'End-Effector';
            app.EndEffectorDropDown = uidropdown(app.LeftPanel);
            app.EndEffectorDropDown.Items = {'None', 'Gripper'};
            app.EndEffectorDropDown.ValueChangedFcn = createCallbackFcn(app, @EndEffectorDropDownValueChanged, true);
            app.EndEffectorDropDown.Value = 'Gripper';
            app.EditButton = uibutton(app.LeftPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Text = 'Edit';
            app.DoFSpinnerLabel = uilabel(app.LeftPanel);
            app.DoFSpinnerLabel.Text = 'DoF';
            app.DoFSpinner = uispinner(app.LeftPanel);
            app.DoFSpinner.ValueChangedFcn = createCallbackFcn(app, @DoFSpinnerValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimePanel = uipanel(app.CenterPanel);
            app.TimeLabel = uilabel(app.TimePanel);
            app.TimeLabel.Text = 'Time:';
            app.secLabel = uilabel(app.TimePanel);
            app.secLabel.Text = '0.00 sec';
            app.x1RealtimeLabel = uilabel(app.TimePanel);
            app.x1RealtimeLabel.Text = '(x1 Real-time)';
            app.q_actq1q2q3q4q5q6q7degLabel = uilabel(app.TimePanel);
            app.q_actq1q2q3q4q5q6q7degLabel.Text = 'q_act=[q1,q2,q3,q4,q5,q6,q7]deg';
            app.ModelPanel = uipanel(app.CenterPanel);
            app.SlowerLabel = uilabel(app.ModelPanel);
            app.SlowerLabel.Text = '(Slower!)';
            app.HighqualitymodelsSwitchLabel = uilabel(app.ModelPanel);
            app.HighqualitymodelsSwitchLabel.Text = 'High quality models:';
            app.HighqualitymodelsSwitch = uiswitch(app.ModelPanel, 'slider');
            app.HighqualitymodelsSwitch.ValueChangedFcn = createCallbackFcn(app, @HighqualitymodelsSwitchValueChanged, true);
            app.DModelLabel = uilabel(app.ModelPanel);
            app.DModelLabel.Text = '3D models:';
            app.DmodelsSwitch = uiswitch(app.ModelPanel, 'slider');
            app.DmodelsSwitch.ValueChangedFcn = createCallbackFcn(app, @DmodelsSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightTabGroup = uitabgroup(app.RightPanel);
            app.InitialJointTab = uitab(app.RightTabGroup);
            app.InitialJointTab.Title = 'Initial Joint';
            app.InitialJointTab.ButtonDownFcn = createCallbackFcn(app, @InitialJointTabButtonDown, true);
            app.Axis1InitialLabel = uilabel(app.InitialJointTab);
            app.Axis1InitialLabel.Text = 'Joint 1 [deg]  ';
            app.Axis1InitialSlider = uislider(app.InitialJointTab);
            app.Axis1InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis1InitialSliderValueChanging, true);
            app.Axis2InitialLabel = uilabel(app.InitialJointTab);
            app.Axis2InitialLabel.Text = 'Joint 2 [deg]  ';
            app.Axis2InitialSlider = uislider(app.InitialJointTab);
            app.Axis2InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis2InitialSliderValueChanging, true);
            app.Axis3InitialLabel = uilabel(app.InitialJointTab);
            app.Axis3InitialLabel.Text = 'Joint 3 [deg]  ';
            app.Axis3InitialSlider = uislider(app.InitialJointTab);
            app.Axis3InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis3InitialSliderValueChanging, true);
            app.Axis4InitialLabel = uilabel(app.InitialJointTab);
            app.Axis4InitialLabel.Text = 'Joint 4 [deg]  ';
            app.Axis4InitialSlider = uislider(app.InitialJointTab);
            app.Axis4InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis4InitialSliderValueChanging, true);
            app.Axis5InitialLabel = uilabel(app.InitialJointTab);
            app.Axis5InitialLabel.Text = 'Joint 5 [deg]  ';
            app.Axis5InitialSlider = uislider(app.InitialJointTab);
            app.Axis5InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis5InitialSliderValueChanging, true);
            app.Axis6InitialLabel = uilabel(app.InitialJointTab);
            app.Axis6InitialLabel.Text = 'Joint 6 [deg]  ';
            app.Axis6InitialSlider = uislider(app.InitialJointTab);
            app.Axis6InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis6InitialSliderValueChanging, true);
            app.Axis7InitialLabel = uilabel(app.InitialJointTab);
            app.Axis7InitialLabel.Text = 'Joint 7 [deg]  ';
            app.Axis7InitialSlider = uislider(app.InitialJointTab);
            app.Axis7InitialSlider.ValueChangingFcn = createCallbackFcn(app, @Axis7InitialSliderValueChanging, true);
            app.GripperInitialLabel = uilabel(app.InitialJointTab);
            app.GripperInitialLabel.Text = 'Gripper [deg]  ';
            app.GripperInitialSlider = uislider(app.InitialJointTab);
            app.GripperInitialSlider.ValueChangingFcn = createCallbackFcn(app, @GripperInitialSliderValueChanging, true);
            app.Axis1InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis1InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1InitialSpinnerValueChanging, true);
            app.Axis1InitialSpinner.Limits = [-160 160];
            app.Axis2InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis2InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2InitialSpinnerValueChanging, true);
            app.Axis2InitialSpinner.Limits = [-137.5 137.5];
            app.Axis3InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis3InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3InitialSpinnerValueChanging, true);
            app.Axis3InitialSpinner.Limits = [-150 150];
            app.Axis4InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis4InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4InitialSpinnerValueChanging, true);
            app.Axis4InitialSpinner.Limits = [-270 270];
            app.Axis5InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis5InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5InitialSpinnerValueChanging, true);
            app.Axis5InitialSpinner.Limits = [-105 120];
            app.Axis6InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis6InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6InitialSpinnerValueChanging, true);
            app.Axis6InitialSpinner.Limits = [-270 270];
            app.Axis7InitialSpinner = uispinner(app.InitialJointTab);
            app.Axis7InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7InitialSpinnerValueChanging, true);
            app.Axis7InitialSpinner.Limits = [-270 270];
            app.GripperInitialSpinner = uispinner(app.InitialJointTab);
            app.GripperInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @GripperInitialSpinnerValueChanging, true);
            app.GripperInitialSpinner.Limits = [-270 270];
            app.FinalJointTab = uitab(app.RightTabGroup);
            app.FinalJointTab.Title = 'Final Joint';
            app.FinalJointTab.ButtonDownFcn = createCallbackFcn(app, @FinalJointTabButtonDown, true);
            app.Axis1FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis1FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1FinalSpinnerValueChanging, true);
            app.Axis1FinalSpinner.Limits = [-160 160];
            app.Axis2FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis2FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2FinalSpinnerValueChanging, true);
            app.Axis2FinalSpinner.Limits = [-137.5 137.5];
            app.Axis3FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis3FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3FinalSpinnerValueChanging, true);
            app.Axis3FinalSpinner.Limits = [-150 150];
            app.Axis4FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis4FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4FinalSpinnerValueChanging, true);
            app.Axis4FinalSpinner.Limits = [-270 270];
            app.Axis5FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis5FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5FinalSpinnerValueChanging, true);
            app.Axis5FinalSpinner.Limits = [-105 120];
            app.Axis6FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis6FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6FinalSpinnerValueChanging, true);
            app.Axis6FinalSpinner.Limits = [-270 270];
            app.Axis7FinalSpinner = uispinner(app.FinalJointTab);
            app.Axis7FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7FinalSpinnerValueChanging, true);
            app.Axis7FinalSpinner.Limits = [-270 270];
            app.GripperFinalSpinner = uispinner(app.FinalJointTab);
            app.GripperFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @GripperFinalSpinnerValueChanging, true);
            app.GripperFinalSpinner.Limits = [-270 270];
            app.Axis1FinalLabel = uilabel(app.FinalJointTab);
            app.Axis1FinalLabel.Text = 'Joint 1 [deg]  ';
            app.Axis1FinalSlider = uislider(app.FinalJointTab);
            app.Axis1FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis1FinalSliderValueChanging, true);
            app.Axis2FinalLabel = uilabel(app.FinalJointTab);
            app.Axis2FinalLabel.Text = 'Joint 2 [deg]  ';
            app.Axis2FinalSlider = uislider(app.FinalJointTab);
            app.Axis2FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis2FinalSliderValueChanging, true);
            app.Axis3FinalLabel = uilabel(app.FinalJointTab);
            app.Axis3FinalLabel.Text = 'Joint 3 [deg]  ';
            app.Axis3FinalSlider = uislider(app.FinalJointTab);
            app.Axis3FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis3FinalSliderValueChanging, true);
            app.Axis4FinalLabel = uilabel(app.FinalJointTab);
            app.Axis4FinalLabel.Text = 'Joint 4 [deg]  ';
            app.Axis4FinalSlider = uislider(app.FinalJointTab);
            app.Axis4FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis4FinalSliderValueChanging, true);
            app.Axis5FinalLabel = uilabel(app.FinalJointTab);
            app.Axis5FinalLabel.Text = 'Joint 5 [deg]  ';
            app.Axis5FinalSlider = uislider(app.FinalJointTab);
            app.Axis5FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis5FinalSliderValueChanging, true);
            app.Axis6FinalLabel = uilabel(app.FinalJointTab);
            app.Axis6FinalLabel.Text = 'Joint 6 [deg]  ';
            app.Axis6FinalSlider = uislider(app.FinalJointTab);
            app.Axis6FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis6FinalSliderValueChanging, true);
            app.Axis7FinalLabel = uilabel(app.FinalJointTab);
            app.Axis7FinalLabel.Text = 'Joint 7 [deg]  ';
            app.Axis7FinalSlider = uislider(app.FinalJointTab);
            app.Axis7FinalSlider.ValueChangingFcn = createCallbackFcn(app, @Axis7FinalSliderValueChanging, true);
            app.GripperFinalLabel = uilabel(app.FinalJointTab);
            app.GripperFinalLabel.Text = 'Gripper [deg]  ';
            app.GripperFinalSlider = uislider(app.FinalJointTab);
            app.GripperFinalSlider.ValueChangingFcn = createCallbackFcn(app, @GripperFinalSliderValueChanging, true);
            app.InitialTaskTab = uitab(app.RightTabGroup);
            app.InitialTaskTab.Title = 'Initial Task';
            app.InitialTaskTab.ButtonDownFcn = createCallbackFcn(app, @InitialTaskTabButtonDown, true);
            app.XAxisInitialSpinner = uispinner(app.InitialTaskTab);
            app.XAxisInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @XAxisInitialSpinnerValueChanging, true);
            app.XAxisInitialSpinner.Limits = [-160 160];
            app.YAxisInitialSpinner = uispinner(app.InitialTaskTab);
            app.YAxisInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @YAxisInitialSpinnerValueChanging, true);
            app.YAxisInitialSpinner.Limits = [-137.5 137.5];
            app.ZAxisInitialSpinner = uispinner(app.InitialTaskTab);
            app.ZAxisInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @ZAxisInitialSpinnerValueChanging, true);
            app.ZAxisInitialSpinner.Limits = [-150 150];
            app.PhiInitialSpinner = uispinner(app.InitialTaskTab);
            app.PhiInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @PhiInitialSpinnerValueChanging, true);
            app.PhiInitialSpinner.Limits = [-270 270];
            app.ThetaInitialSpinner = uispinner(app.InitialTaskTab);
            app.ThetaInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @ThetaInitialSpinnerValueChanging, true);
            app.ThetaInitialSpinner.Limits = [-105 120];
            app.PsiInitialSpinner = uispinner(app.InitialTaskTab);
            app.PsiInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @PsiInitialSpinnerValueChanging, true);
            app.PsiInitialSpinner.Limits = [-270 270];
            app.Axis7InitialSpinner_2 = uispinner(app.InitialTaskTab);
            app.Axis7InitialSpinner_2.Limits = [-270 270];
            app.Axis7InitialSpinner_2.Visible = 'off';
            app.GripperTaskInitialSpinner = uispinner(app.InitialTaskTab);
            app.GripperTaskInitialSpinner.ValueChangingFcn = createCallbackFcn(app, @GripperTaskInitialSpinnerValueChanging, true);
            app.GripperTaskInitialSpinner.Limits = [-270 270];
            app.XAxisInitialLabel = uilabel(app.InitialTaskTab);
            app.XAxisInitialLabel.Text = 'X-Axis [mm]  ';
            app.XAxisInitialSlider = uislider(app.InitialTaskTab);
            app.XAxisInitialSlider.ValueChangingFcn = createCallbackFcn(app, @XAxisInitialSliderValueChanging, true);
            app.YAxisInitialLabel = uilabel(app.InitialTaskTab);
            app.YAxisInitialLabel.Text = 'Y-Axis [mm]  ';
            app.YAxisInitialSlider = uislider(app.InitialTaskTab);
            app.YAxisInitialSlider.ValueChangingFcn = createCallbackFcn(app, @YAxisInitialSliderValueChanging, true);
            app.ZAxisInitialLabel = uilabel(app.InitialTaskTab);
            app.ZAxisInitialLabel.Text = 'Z-Axis [mm]  ';
            app.ZAxisInitialSlider = uislider(app.InitialTaskTab);
            app.ZAxisInitialSlider.ValueChangingFcn = createCallbackFcn(app, @ZAxisInitialSliderValueChanging, true);
            app.PhiInitialLabel = uilabel(app.InitialTaskTab);
            app.PhiInitialLabel.Text = 'Phi   [deg]  ';
            app.PhiInitialSlider = uislider(app.InitialTaskTab);
            app.PhiInitialSlider.ValueChangingFcn = createCallbackFcn(app, @PhiInitialSliderValueChanging, true);
            app.ThetaInitialLabel = uilabel(app.InitialTaskTab);
            app.ThetaInitialLabel.Text = 'Theta  [deg]  ';
            app.ThetaInitialSlider = uislider(app.InitialTaskTab);
            app.ThetaInitialSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaInitialSliderValueChanging, true);
            app.PsiInitialLabel = uilabel(app.InitialTaskTab);
            app.PsiInitialLabel.Text = {'Psi   '; '[deg]  '};
            app.PsiInitialSlider = uislider(app.InitialTaskTab);
            app.PsiInitialSlider.ValueChangingFcn = createCallbackFcn(app, @PsiInitialSliderValueChanging, true);
            app.Axis7InitialLabel_2 = uilabel(app.InitialTaskTab);
            app.Axis7InitialLabel_2.Visible = 'off';
            app.Axis7InitialLabel_2.Text = 'Joint 7 [deg]  ';
            app.Axis7InitialSlider_2 = uislider(app.InitialTaskTab);
            app.Axis7InitialSlider_2.Visible = 'off';
            app.GripperTaskInitialLabel = uilabel(app.InitialTaskTab);
            app.GripperTaskInitialLabel.Text = 'Gripper [deg]  ';
            app.GripperTaskInitialSlider = uislider(app.InitialTaskTab);
            app.GripperTaskInitialSlider.ValueChangingFcn = createCallbackFcn(app, @GripperTaskInitialSliderValueChanging, true);
            app.FinalTaskTab = uitab(app.RightTabGroup);
            app.FinalTaskTab.Title = 'Final Task';
            app.FinalTaskTab.ButtonDownFcn = createCallbackFcn(app, @FinalTaskTabButtonDown, true);
            app.XAxisFinalSpinner = uispinner(app.FinalTaskTab);
            app.XAxisFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @XAxisFinalSpinnerValueChanging, true);
            app.XAxisFinalSpinner.Limits = [-160 160];
            app.YAxisFinalSpinner = uispinner(app.FinalTaskTab);
            app.YAxisFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @YAxisFinalSpinnerValueChanging, true);
            app.YAxisFinalSpinner.Limits = [-137.5 137.5];
            app.ZAxisFinalSpinner = uispinner(app.FinalTaskTab);
            app.ZAxisFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @ZAxisFinalSpinnerValueChanging, true);
            app.ZAxisFinalSpinner.Limits = [-150 150];
            app.PhiFinalSpinner = uispinner(app.FinalTaskTab);
            app.PhiFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @PhiFinalSpinnerValueChanging, true);
            app.PhiFinalSpinner.Limits = [-270 270];
            app.ThetaFinalSpinner = uispinner(app.FinalTaskTab);
            app.ThetaFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @ThetaFinalSpinnerValueChanging, true);
            app.ThetaFinalSpinner.Limits = [-105 120];
            app.PsiFinalSpinner = uispinner(app.FinalTaskTab);
            app.PsiFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @PsiFinalSpinnerValueChanging, true);
            app.PsiFinalSpinner.Limits = [-270 270];
            app.Axis7FinalSpinner_2 = uispinner(app.FinalTaskTab);
            app.Axis7FinalSpinner_2.Limits = [-270 270];
            app.Axis7FinalSpinner_2.Visible = 'off';
            app.GripperTaskFinalSpinner = uispinner(app.FinalTaskTab);
            app.GripperTaskFinalSpinner.ValueChangingFcn = createCallbackFcn(app, @GripperTaskFinalSpinnerValueChanging, true);
            app.GripperTaskFinalSpinner.Limits = [-270 270];
            app.XAxisFinalLabel = uilabel(app.FinalTaskTab);
            app.XAxisFinalLabel.Text = 'X-Axis [mm]  ';
            app.XAxisFinalSlider = uislider(app.FinalTaskTab);
            app.XAxisFinalSlider.ValueChangingFcn = createCallbackFcn(app, @XAxisFinalSliderValueChanging, true);
            app.YAxisFinalLabel = uilabel(app.FinalTaskTab);
            app.YAxisFinalLabel.Text = 'Y-Axis [mm]  ';
            app.YAxisFinalSlider = uislider(app.FinalTaskTab);
            app.YAxisFinalSlider.ValueChangingFcn = createCallbackFcn(app, @YAxisFinalSliderValueChanging, true);
            app.ZAxisFinalLabel = uilabel(app.FinalTaskTab);
            app.ZAxisFinalLabel.Text = 'Z-Axis [mm]  ';
            app.ZAxisFinalSlider = uislider(app.FinalTaskTab);
            app.ZAxisFinalSlider.ValueChangingFcn = createCallbackFcn(app, @ZAxisFinalSliderValueChanging, true);
            app.PhiFinalLabel = uilabel(app.FinalTaskTab);
            app.PhiFinalLabel.Text = 'Phi   [deg]  ';
            app.PhiFinalSlider = uislider(app.FinalTaskTab);
            app.PhiFinalSlider.ValueChangingFcn = createCallbackFcn(app, @PhiFinalSliderValueChanging, true);
            app.ThetaFinalLabel = uilabel(app.FinalTaskTab);
            app.ThetaFinalLabel.Text = 'Theta  [deg]  ';
            app.ThetaFinalSlider = uislider(app.FinalTaskTab);
            app.ThetaFinalSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaFinalSliderValueChanging, true);
            app.PsiFinalLabel = uilabel(app.FinalTaskTab);
            app.PsiFinalLabel.Text = 'Psi   [deg]  ';
            app.PsiFinalSlider = uislider(app.FinalTaskTab);
            app.PsiFinalSlider.ValueChangingFcn = createCallbackFcn(app, @PsiFinalSliderValueChanging, true);
            app.Axis7FinalLabel_2 = uilabel(app.FinalTaskTab);
            app.Axis7FinalLabel_2.Visible = 'off';
            app.Axis7FinalLabel_2.Text = 'Joint 7 [deg]  ';
            app.Axis7FinalSlider_2 = uislider(app.FinalTaskTab);
            app.Axis7FinalSlider_2.Visible = 'off';
            app.GripperTaskFinalLabel = uilabel(app.FinalTaskTab);
            app.GripperTaskFinalLabel.Text = 'Gripper [deg]  ';
            app.GripperTaskFinalSlider = uislider(app.FinalTaskTab);
            app.GripperTaskFinalSlider.ValueChangingFcn = createCallbackFcn(app, @GripperTaskFinalSliderValueChanging, true);
            app.TrajectoryPanel = uipanel(app.RightPanel);
            app.TrajectoryPanel.Title = 'Trajectory';
            app.LimitsLabel = uilabel(app.TrajectoryPanel);
            app.LimitsLabel.Text = 'Limits:';
            app.VelPrcLabel = uilabel(app.TrajectoryPanel);
            app.VelPrcLabel.Text = '%';
            app.AccPrcLabel = uilabel(app.TrajectoryPanel);
            app.AccPrcLabel.Text = '%';
            app.InterpolationLabel = uilabel(app.TrajectoryPanel);
            app.InterpolationLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.TrajectoryPanel);
            app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Quintic'};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Value = 'Quintic';
            app.AccEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.AccEditFieldLabel.Text = 'Acc';
            app.AccEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.AccEditField.Limits = [0 100];
            app.AccEditField.ValueChangedFcn = createCallbackFcn(app, @AccEditFieldValueChanged, true);
            app.AccEditField.Value = 10;
            app.VelEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.VelEditField.Limits = [0 100];
            app.VelEditField.ValueChangedFcn = createCallbackFcn(app, @VelEditFieldValueChanged, true);
            app.VelEditField.Value = 10;
            app.ButtonGroup_8 = uibuttongroup(app.TrajectoryPanel);
            app.JointButton = uiradiobutton(app.ButtonGroup_8);
            app.JointButton.Enable = 'off';
            app.JointButton.Text = 'Joint';
            app.JointButton.Value = true;
            app.TaskButton = uiradiobutton(app.ButtonGroup_8);
            app.TaskButton.Enable = 'off';
            app.TaskButton.Text = 'Task';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end